classdef ContrastProfileDistanceThresholdSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        Left10Button            matlab.ui.control.Button
        Right10Button           matlab.ui.control.Button
        PreviousButton          matlab.ui.control.Button
        NextButton              matlab.ui.control.Button
        SelectButton            matlab.ui.control.Button
        SampleIndexLabel        matlab.ui.control.Label
        DistanceThresholdLabel  matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        CandidateIndexLabel     matlab.ui.control.Label
        Label_3                 matlab.ui.control.Label
        IndexSlider             matlab.ui.control.Slider
        IndexSlider_2           matlab.ui.control.Slider
    end

    
    properties (Access = private)
        plato; % Description
        trainValidation; % Description
        
        subLength %Subsequence length of Plato
        
        validationCandidates;
        currentValidationCandidates;
        
        sortedCandidates;
        sortedCandidateIndices;
        sortedCandidateDistances;
        
        targetIndex;
        distanceThreshold;
        
        pcaCoef;
        
        numShownCandidates;
        
    end
    
    methods (Access = private)
        function plotCandidateDistances(app)
            colorGreen = [0.3, 0.8, 0.3];
            colorRed = [0.8, 0.3, 0.3];
            colorLightGray = [0.8, 0.8, 0.8];
            colorGray = [0.3, 0.3, 0.3];
            colorPurple = [0.8, 0.3, 0.8];
            
            plot(app.UIAxes3,1,1);
            xMin = 1;
            xMax = length(app.sortedCandidateDistances);
            yMin = app.sortedCandidateDistances(1);
            yMax = app.sortedCandidateDistances(end);
            
            hold(app.UIAxes3,"on");
            lineValue = app.targetIndex;
            plot(app.UIAxes3, [lineValue, lineValue], [0, app.sortedCandidateDistances(end)], 'Color', colorLightGray);
        
            startIndex = 1;
            endIndex = app.targetIndex;
            
            plot(app.UIAxes3, startIndex:endIndex, app.sortedCandidateDistances(startIndex:endIndex), 'Color', colorGreen, "LineWidth", 2);
            
            
            %%% After Target Threshold
            startIndex = app.targetIndex;
            endIndex = min(length(app.sortedCandidateDistances), app.numShownCandidates);
            
            plot(app.UIAxes3, startIndex:endIndex, app.sortedCandidateDistances(startIndex:endIndex), 'Color', colorRed, "LineWidth", 2);
            
            startIndex = app.numShownCandidates;
            endIndex = length(app.sortedCandidateDistances);
            if startIndex < endIndex
                plot(app.UIAxes3, startIndex:endIndex, app.sortedCandidateDistances(startIndex:endIndex), 'Color', colorGray, "LineWidth", 2);
            end
            
            xlim(app.UIAxes3, [xMin, xMax]);
            ylim(app.UIAxes3, [yMin, yMax]);
            hold(app.UIAxes3,"off");
        end
        
        function plotCandidates(app)
            colorGreen = [0.3, 0.8, 0.3];
            colorRed = [0.8, 0.3, 0.3];
            colorGray = [0.3, 0.3, 0.3];
            colorPurple = [0.8, 0.3, 0.8];
            
            inset = 0.9;
            contextLength = app.subLength;
            plot(app.UIAxes, [0, app.subLength + 2*contextLength], [-5, -5], '--', 'Color', colorGray);
            hold(app.UIAxes,'on');
            xCandidate = contextLength+1:contextLength+1+app.subLength - 1;
            
            tempTS = app.plato;
            tempMin = min(tempTS);
            tempMax = max(tempTS);
            tempRange = max(1e-5, tempMax - tempMin);
            
            tempPlot = 0.05 + inset*(tempTS - tempMin)/tempRange;
            
            plot(app.UIAxes, xCandidate, tempPlot, "Color", colorPurple);
            candidateIndex = app.targetIndex - 4;
            for plotIndex = 1:10
                if candidateIndex <= 0
                   continue 
                end
                startIndexContext = app.sortedCandidateIndices(candidateIndex) - contextLength;
                endIndexContext = app.sortedCandidateIndices(candidateIndex) + app.subLength + contextLength - 1;
                
                if startIndexContext <= 0 || endIndexContext > length(app.trainValidation)
                   continue 
                end
                tempTS = app.trainValidation(startIndexContext:endIndexContext);
                tempMin = min(tempTS);
                tempMax = max(tempTS);
                tempRange = max(1e-5, tempMax - tempMin);
                
                tempPlot = 0.05 + inset*(tempTS - tempMin)/tempRange;
                plot(app.UIAxes, -plotIndex + tempPlot, 'Color', colorGray);
                
                
                startIndex = app.sortedCandidateIndices(candidateIndex);
                endIndex = app.sortedCandidateIndices(candidateIndex) + app.subLength - 1;
                
                tempTS = app.trainValidation(startIndex:endIndex);
                
                
                tempPlot = 0.05 + inset*(tempTS - tempMin)/tempRange;
                color = colorGreen;
                if plotIndex >5
                    color = colorRed;
                end
                plot(app.UIAxes,xCandidate, -plotIndex + tempPlot, 'Color', color);
                
                candidateIndex = candidateIndex + 1;
            end
            ylim(app.UIAxes,[-11,1]);
            hold(app.UIAxes,'off');
        end
        
        function plotPCA(app)
            colorGreen = [0.3, 0.8, 0.3];
            colorDarkGreen = [0.15, 0.5, 0.15];
            colorRed = [0.8, 0.3, 0.3];
                        
            xMin = min(app.pcaCoef(1:app.numShownCandidates, 1));
            xMax = max(app.pcaCoef(1:app.numShownCandidates, 1));
            yMin = min(app.pcaCoef(1:app.numShownCandidates, 2));
            yMax = max(app.pcaCoef(1:app.numShownCandidates, 2));
            
            plot(app.UIAxes2,0);
            hold(app.UIAxes2,"on");
            
            startIndex = 1;
            endIndex = min(app.targetIndex, app.numShownCandidates);
            scatter(app.UIAxes2, app.pcaCoef(startIndex:endIndex,1), app.pcaCoef(startIndex:endIndex,2), 2, 'filled', 'MarkerFaceColor', colorGreen);
            
            startIndex = app.targetIndex + 1;
            endIndex = min(size(app.sortedCandidates,1), app.numShownCandidates);
            scatter(app.UIAxes2, app.pcaCoef(startIndex:endIndex,1), app.pcaCoef(startIndex:endIndex, 2), 2, 'filled', 'MarkerFaceColor', colorRed);
            
            startIndex = min(app.targetIndex, app.numShownCandidates);
            scatter(app.UIAxes2, app.pcaCoef(startIndex,1), app.pcaCoef(startIndex,2),100,'x', 'MarkerEdgeColor', colorDarkGreen, 'LineWidth',2);
            
            xlim(app.UIAxes2, [xMin, xMax]);
            ylim(app.UIAxes2, [yMin, yMax]);
            hold(app.UIAxes2, "off");
            
        end
        function indexStep(app, step)
            targetIndex = app.targetIndex;
            targetIndex = max(targetIndex + step, 1);
            targetIndex = min(targetIndex, size(app.sortedCandidateDistances,1));
            app.targetIndex = targetIndex;
            
            app.distanceThreshold = app.sortedCandidateDistances(app.targetIndex);
            
            app.IndexSlider.Value = app.targetIndex;
            app.Label.Text = sprintf("%d",app.sortedCandidateIndices(app.targetIndex));
            app.Label_2.Text = sprintf("%d", app.targetIndex);
            app.Label_3.Text = sprintf("%.5f", app.distanceThreshold);
            
            
            app.plotCandidates();
            app.plotPCA();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, plato, trainValidation)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%   Store Input Variables  %%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.plato = plato(:);
            app.trainValidation = trainValidation(:);
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%   Store Input Variables  %%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.subLength = length(plato);
            
            distanceProfile = MASS_V2(app.trainValidation, app.plato);
            
            [sortedCandidateIndices, sortedCandidateDistances] = allLowestDistanceIndices(distanceProfile, app.subLength, app.subLength);
            numCandidates = size(sortedCandidateIndices,1);
            
            app.sortedCandidateIndices = sortedCandidateIndices;
            app.sortedCandidates = zeros(numCandidates,length(plato));
            app.sortedCandidateDistances = sortedCandidateDistances;
            for ii = 1:numCandidates
                startIndex = app.sortedCandidateIndices(ii);
                endIndex = startIndex + app.subLength - 1;
                app.sortedCandidates(ii,:) = app.trainValidation(startIndex:endIndex);
            end
            
            app.numShownCandidates = length(app.sortedCandidateDistances);
            app.IndexSlider_2.Limits = [1, app.numShownCandidates];
            app.IndexSlider_2.Value = app.numShownCandidates;
            
            %%% State update
            app.targetIndex = floor(numCandidates/2);
            app.distanceThreshold = app.sortedCandidateDistances(app.targetIndex);
            
            %%% UI Slider Update
            app.IndexSlider.Limits = [0,numCandidates];
            app.IndexSlider.Value = floor(numCandidates/2);
            
            
            %%% Setup Plots
            
            %%% Candidate Inspector
            box(app.UIAxes, "off");
%             set(app.UIAxes, 'xtick',[])
%             set(app.UIAxes, 'xticklabel',[])
            set(app.UIAxes, 'ytick',[])
            set(app.UIAxes, 'yticklabel',[])
            
            %%% PCA
            box(app.UIAxes2, "off");
            set(app.UIAxes2, 'xtick',[])
            set(app.UIAxes2, 'xticklabel',[])
            set(app.UIAxes2, 'ytick',[])
            set(app.UIAxes2, 'yticklabel',[])
            
            %%% PCA
            box(app.UIAxes3, "off");
            set(app.UIAxes3, 'xtick',[])
            set(app.UIAxes3, 'xticklabel',[])
%             set(app.UIAxes2, 'ytick',[])
%             set(app.UIAxes2, 'yticklabel',[])

%             app.IndexSlider.MajorTickLabels = [];
%             app.IndexSlider.MajorTicks = [];
%             app.IndexSlider.MinorTicks = [];
            
            app.plotCandidateDistances();

            app.plotCandidates();
            
            app.pcaCoef = pca(app.sortedCandidates');
            
            
            app.plotPCA();
        end

        % Value changing function: IndexSlider
        function IndexSliderValueChanging(app, event)
            changingValue = event.Value;
            
            valueInt = round(changingValue);
            valueInt = max(1,valueInt);
            valueInt = min(length(app.sortedCandidateDistances), valueInt);
            app.targetIndex = round(valueInt);
            
            
            app.distanceThreshold = app.sortedCandidateDistances(app.targetIndex);
            
            app.Label.Text = sprintf("%d",app.sortedCandidateIndices(app.targetIndex));
            app.Label_2.Text = sprintf("%d",app.targetIndex);
            app.Label_3.Text = sprintf("%.5f", app.distanceThreshold);
            
            
            app.plotCandidateDistances();
            
        end

        % Value changed function: IndexSlider
        function IndexSliderValueChanged(app, event)
            value = app.IndexSlider.Value;


            app.Label.Text = sprintf("%d",app.sortedCandidateIndices(uint32(app.targetIndex)));
            app.Label_2.Text = sprintf("%d",app.targetIndex);
            app.Label_3.Text = sprintf("%.5f", app.sortedCandidateDistances(app.targetIndex));
            
            app.plotCandidates();
            app.plotPCA();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.indexStep(1);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.indexStep(-1);
        end

        % Button pushed function: Left10Button
        function Left10ButtonPushed(app, event)
            app.indexStep(-10);
        end

        % Button pushed function: Right10Button
        function Right10ButtonPushed(app, event)
            app.indexStep(10);
        end

        % Value changed function: IndexSlider_2
        function IndexSlider_2ValueChanged(app, event)
            value = app.IndexSlider_2.Value;
            
            app.numShownCandidates = value;
            app.plotPCA();
        end

        % Value changing function: IndexSlider_2
        function IndexSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            
            valueInt = round(changingValue);
            valueInt = max(1,valueInt);
            app.numShownCandidates = min(length(app.sortedCandidateDistances), valueInt);
            
            app.plotCandidateDistances();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Candidate Inspector')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, '')
            app.Left10Button = uibutton(app.UIFigure, 'push');
            app.Left10Button.ButtonPushedFcn = createCallbackFcn(app, @Left10ButtonPushed, true);
            app.Left10Button.Text = 'Left 10';
            app.Right10Button = uibutton(app.UIFigure, 'push');
            app.Right10Button.ButtonPushedFcn = createCallbackFcn(app, @Right10ButtonPushed, true);
            app.Right10Button.Text = 'Right 10';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.Text = 'Select';
            app.SampleIndexLabel = uilabel(app.UIFigure);
            app.SampleIndexLabel.Text = 'Sample Index: ';
            app.DistanceThresholdLabel = uilabel(app.UIFigure);
            app.DistanceThresholdLabel.Text = 'Distance Threshold: ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '0';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'PCA')
            xlabel(app.UIAxes2, 'Component 1')
            ylabel(app.UIAxes2, 'Component 2')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Candidate Euclidean Distance')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, 'Euclidean Distance')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.CandidateIndexLabel = uilabel(app.UIFigure);
            app.CandidateIndexLabel.Text = 'Candidate Index:';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '0';
            app.IndexSlider = uislider(app.UIFigure);
            app.IndexSlider.MajorTicks = [];
            app.IndexSlider.MajorTickLabels = {''};
            app.IndexSlider.ValueChangedFcn = createCallbackFcn(app, @IndexSliderValueChanged, true);
            app.IndexSlider.ValueChangingFcn = createCallbackFcn(app, @IndexSliderValueChanging, true);
            app.IndexSlider.MinorTicks = [];
            app.IndexSlider_2 = uislider(app.UIFigure);
            app.IndexSlider_2.MajorTicks = [];
            app.IndexSlider_2.MajorTickLabels = {''};
            app.IndexSlider_2.ValueChangedFcn = createCallbackFcn(app, @IndexSlider_2ValueChanged, true);
            app.IndexSlider_2.ValueChangingFcn = createCallbackFcn(app, @IndexSlider_2ValueChanging, true);
            app.IndexSlider_2.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ContrastProfileDistanceThresholdSelection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end