classdef visualizeMMP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxesMMP                    matlab.ui.control.UIAxes
        XSurfaceSlider               matlab.ui.control.Slider
        YSurfaceSlider               matlab.ui.control.Slider
        TabGroup                     matlab.ui.container.TabGroup
        TimeseriesPlotsTab           matlab.ui.container.Tab
        UIAxesSubsequence            matlab.ui.control.UIAxes
        UIAxesMotifs                 matlab.ui.control.UIAxes
        ZoomSlider                   matlab.ui.control.Slider
        UIAxesTimeseries             matlab.ui.control.UIAxes
        IndexSlider                  matlab.ui.control.Slider
        ZoomEditFieldLabel           matlab.ui.control.Label
        ZoomEditField                matlab.ui.control.NumericEditField
        LabelSubLen                  matlab.ui.control.Label
        LabelTimeIndex               matlab.ui.control.Label
        LabelRawMMP                  matlab.ui.control.Label
        LabelTimeIndexNN             matlab.ui.control.Label
        OptionsTab                   matlab.ui.container.Tab
        ContrastButtonGroup          matlab.ui.container.ButtonGroup
        LowContrastButton            matlab.ui.control.RadioButton
        HighContrastButton           matlab.ui.control.RadioButton
        BinaryButton                 matlab.ui.control.RadioButton
        PefectMatchButton            matlab.ui.control.RadioButton
        ProfileIndexOverlayCheckBox  matlab.ui.control.CheckBox
        ThreshEditFieldLabel         matlab.ui.control.Label
        ThreshEditField              matlab.ui.control.NumericEditField
        ThresholdSlider              matlab.ui.control.Slider
        LiveUpdateMotifsCheckBox     matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        data;
        MMP;
        profileIndices;
        subLenSeries;
        
        maxMMP;
        minMMP;
        
        startIndex;
        endIndex;
        centerIndex;
        
        zoomExclusionPlotLeft;
        zoomExclusionPlotRight;
        
        surfaceCursorPlot;
        surfaceCursorPlot2;
        surfaceCursorPlot3;
        surfaceCursorPlot4;
        surfaceCursorPosition;
        
        subsequenceOutlinePlot;
        subsequenceColorPlot;
        subsequenceMarkerOutlinePlot;
        subsequenceMarkerColorPlot;
        subsequenceOutlineNNPlot;
        subsequenceColorNNPlot;
        subsequenceMarkerOutlineNNPlot;
        subsequenceMarkerColorNNPlot;
        
        subsequenceZoomOutlinePlot;
        subsequenceZoomColorPlot;
        subsequenceZoomMarkerOutlinePlot;
        subsequenceZoomMarkerColorPlot;
        subsequenceZoomOutlineNNPlot;
        subsequenceZoomColorNNPlot;
        subsequenceZoomMarkerOutlineNNPlot;
        subsequenceZoomMarkerColorNNPlot;
        
        motifTimeIndex1;
        motifTimeIndex2;
        motifSubLenIndex;
        
        %for thresholding, number of orders of magnitude to sweep over
        numOrders;
        
        percentile;
        percentileValues;
        
        contrastMode;
        CONTRAST_LOW;
        CONTRAST_BINARY;
        CONTRAST_HIGH;
        CONTRAST_EXACT;
        
        showProfileIndicies;
        liveUpdateMotifs
        
        colormapDistances;
        colormapIndices;
        
    end
    
    methods (Access = private)
        
        
        function plotSurface(app)
            MMP = app.MMP;
            subLenSeries = app.subLenSeries;
            profileIndices = app.profileIndices;
            startIndex = app.startIndex;
            endIndex = app.endIndex;
            
            numPlotPixels = 1024;
            step = 1;
            if endIndex - startIndex >= numPlotPixels
                step = floor((endIndex-startIndex)/numPlotPixels);
            end
            
            plotIndices = startIndex:step:endIndex;
            canvasShade = MMP(:,plotIndices);
            
            %adjust contrast
            tempCanvas = canvasShade;
            numPercentiles = size(app.percentileValues,2);
            for index=2:numPercentiles
                selection = app.percentileValues(index-1) < canvasShade & canvasShade <= app.percentileValues(index);
                tempMMP = canvasShade(selection);
                tempMMP = normalize(tempMMP,'range');
                tempCanvas(selection) = tempMMP*(1/(numPercentiles-1)) + (index-2)/(numPercentiles-1);
            end
            canvasShade = tempCanvas;
            canvasShade = app.centerOnThreshold(canvasShade);
            
            nanIndices = isnan(app.profileIndices(:,plotIndices));
            canvasShade(nanIndices) = 1;
            nanIndices = app.profileIndices(:,plotIndices) == 1;
            canvasShade(nanIndices) = 1;
            
            
%           %%% Profile Indices with Color
            if app.showProfileIndicies == true
                profileIndices = app.profileIndices(:,plotIndices);
                canvas = zeros(size(canvasShade,1),size(canvasShade,2),3);
                for i=1:size(app.MMP,1)
                    if i >= 2
                        indices = profileIndices(i,:);
                        
                    else
                     indices = linspace(1,size(app.MMP,2),size(canvas,2));
                    end
                    indices = indices * 256/size(app.MMP,2)+1;
                        indices = ceil(indices);
                    
                    indices = min(indices,256);
                    canvas(i,:,:) = app.colormapIndices(indices,:);
                end
                canvas = 1-canvas;
                canvas = canvas.*(1-canvasShade);
                canvas = 1-canvas;
            else
                canvas = canvasShade;
            end
            
  
            for i=1:length(app.subLenSeries)-1
                hold(app.UIAxesMMP, 'on');
                X = [plotIndices(1),plotIndices(end)];
                Y = app.subLenSeries(i:i+1);
                C = canvas(i:i+1,:,:);
                imagesc(app.UIAxesMMP,'XData',X,'YData',Y,'CData',C);
            end

            hold(app.UIAxesMMP, 'off');
            
            xlim(app.UIAxesMMP,[startIndex,endIndex]);
            ylim(app.UIAxesMMP,[subLenSeries(1),subLenSeries(end)]);
            app.UIAxesMMP.YDir = 'normal';

%             app.plotColorbar();
            
            app.plotSurfaceCursor();
        end
    
        function plotColorbar(app)
            if app.showProfileIndicies == true
                cursorColorIndex = ceil(256*app.motifTimeIndex2/length(app.data));
                cursorColorIndex = min(256, cursorColorIndex);
                cursorColor = app.colormapIndices(cursorColorIndex,:);
                cursorColormap = app.colormapDistances(1:8:end,:);
                cursorColormap = (1-cursorColormap) .* (1-cursorColor);

                cursorColormap = 1-cursorColormap;
                colormap(app.UIAxesMMP, cursorColormap);
                cbh = colorbar(app.UIAxesMMP);
                set(cbh,'YTick',[0,1]);
            else
                colormap(app.UIAxesMMP, app.colormapDistances);
                cbh = colorbar(app.UIAxesMMP);
            end
        end
        
        function thresholdValue = getThresholdValue(app, thresholdRatio)
%             powerVal = -(1-thresholdRatio)*app.numOrders;
%             thresholdValue = power(10,powerVal);

              thresholdValue = thresholdRatio;
        end
    
        function thresholdRatio = getThresholdRatio(app, thresholdValue)
            powerVal = log10(thresholdValue);
            thresholdRatio = 1 + powerVal/app.numOrders;
            thresholdRatio = max(0, thresholdRatio);
        end
        
        function updateSurfaceView(app)
            centerIndexRatio = app.IndexSlider.Value;
          
            centerIndex = ceil(centerIndexRatio * size(app.MMP,2));
            range = ceil(power(10,app.ZoomSlider.Value));
            range = min(size(app.MMP,2), range);
            startIndex = max(ceil(centerIndex - ceil(range/2)), 1);
            startIndex = ceil(startIndex);
            endIndex = min(ceil(centerIndex + ceil(range/2)), size(app.MMP,2));
            endIndex = ceil(endIndex);
            if endIndex < range
                startIndex = 1;
                endIndex = min(range, size(app.MMP,2));
            end
            if startIndex > size(app.MMP,2)-range + 1
                startIndex = max(size(app.MMP,2)-range + 1, 1);
                endIndex = size(app.MMP,2);
            end
            app.startIndex = ceil(startIndex);
            app.endIndex = ceil(endIndex);
            xlim(app.UIAxesMMP, [app.startIndex, app.endIndex]);
            app.plotSurface();
        end
    
        function canvas = centerOnThreshold(app, canvas)
            threshold = app.ThreshEditField.Value;
                
            if app.contrastMode == app.CONTRAST_HIGH
                canvas = 1./(1+exp(-10.*(canvas-threshold)));
                canvas = app.mapThresholdAs50Percent(canvas, threshold);
                
            elseif app.contrastMode == app.CONTRAST_BINARY
                indices = canvas <= threshold;
                canvas(indices) = 0;
                canvas(~indices) = 1;
                
            elseif app.contrastMode == app.CONTRAST_EXACT
                threshold = 1e-10;
                indices = canvas <= threshold;
                canvas(indices) = 0;
                canvas(~indices) = 1;
                
            else %app.contrastMode == CONTRAST_LOW
                canvas = app.mapThresholdAs50Percent(canvas, threshold);
            end            
        end
        
        function canvas = mapThresholdAs50Percent(app, canvas, threshold)
            indices = canvas <= threshold;
            canvas(indices) = normalize(canvas(indices),'range');
            canvas(indices) = canvas(indices)./2;

            indices = ~indices;
            canvas(indices) = normalize(canvas(indices),'range');
            canvas(indices) = canvas(indices)./2 + 0.5;
        end
   
        function plotZoomExclusionZone(app, centerIndex, subLen)
            startIndex  = max(ceil(centerIndex - subLen/2),1);
            endIndex    = min(ceil(centerIndex + subLen/2), length(app.data));
            
            if app.zoomExclusionPlotLeft ~= 0
                delete(app.zoomExclusionPlotLeft);
            end
            if app.zoomExclusionPlotRight ~= 0
                delete(app.zoomExclusionPlotRight);
            end
            
            rectHeight = max(app.data) - min(app.data);
            if startIndex > 1
%                 app.zoomExclusionPlotLeft = rectangle(app.UIAxesTimeseries, 'Position', [1,min(app.data),startIndex,rectHeight],'FaceColor',[0.8 0.8 0.8]);
                x1 = [1, startIndex, startIndex, 1];
                y1 = [min(app.data), min(app.data), max(app.data), max(app.data)];
                app.zoomExclusionPlotLeft = patch(app.UIAxesTimeseries, x1,y1,'black','FaceAlpha',0.2);

            end
            
            if endIndex < length(app.data)
                x2 = [endIndex, length(app.data), length(app.data), endIndex];
                y2 = [min(app.data), min(app.data), max(app.data), max(app.data)];
                app.zoomExclusionPlotRight = patch(app.UIAxesTimeseries, x2,y2,'black','FaceAlpha',0.2);
            end
            
%             app.zoomExclusionPlotLeft.FaceColorAlpha = .2;
%             uistack(app.zoomExclusionPlotRight, 'bottom');
        end
    
        function plotSurfaceCursor(app)
            
            xIndex = app.surfaceCursorPosition(1);
            yIndex = app.surfaceCursorPosition(2);
            zIndex = app.surfaceCursorPosition(3);
            
            xIndexNN = app.motifTimeIndex2;
            if app.surfaceCursorPlot ~= 0
                delete(app.surfaceCursorPlot);
                delete(app.surfaceCursorPlot2);
                delete(app.surfaceCursorPlot3);
                delete(app.surfaceCursorPlot4);
            end
            
            hold(app.UIAxesMMP, 'on');
            subLen = app.subLenSeries(min(app.motifSubLenIndex));
            
            colorIndex = ceil(xIndexNN/size(app.MMP,2)*size(app.colormapIndices,1));
            markerColor = app.colormapIndices(colorIndex,:);
            
            colorIndexNN = ceil(xIndex/size(app.MMP,2)*size(app.colormapIndices,1));
            markerColorNN = app.colormapIndices(colorIndexNN,:);
            
            yOffset = 0.03*(app.subLenSeries(end) - app.subLenSeries(1)+1);
            app.surfaceCursorPlot = plot3(app.UIAxesMMP,xIndex, yIndex+yOffset,zIndex,'v','MarkerSize',20,'LineWidth',2,'MarkerEdgeColor',markerColorNN,'MarkerFaceColor',markerColorNN);
            app.surfaceCursorPlot2 = plot3(app.UIAxesMMP,xIndex, yIndex+yOffset,zIndex,'o','MarkerSize',10,'LineWidth',1,'MarkerFaceColor',markerColor,'MarkerEdgeColor','k');


            app.surfaceCursorPlot3 = plot3(app.UIAxesMMP,xIndexNN, yIndex+yOffset,zIndex,'v','MarkerSize',20,'LineWidth',2,'MarkerEdgeColor',markerColor,'MarkerFaceColor',markerColor);
            app.surfaceCursorPlot4 = plot3(app.UIAxesMMP,xIndexNN, yIndex+yOffset,zIndex,'o','MarkerSize',10,'LineWidth',1,'MarkerFaceColor',markerColorNN,'MarkerEdgeColor','w');

            hold(app.UIAxesMMP, 'off');
            
        end
    
        function plotMotifs(app)
            subLength = app.subLenSeries(app.motifSubLenIndex);
            
            startIndex1 = app.motifTimeIndex1;
            endIndex1 = startIndex1 + subLength;
        
            startIndex2 = app.profileIndices(app.motifSubLenIndex, startIndex1);
            endIndex2 = startIndex2 + subLength;
            
            
            cm = zeros(2,size(app.colormapIndices,1),3);
            cm(1,:,:) = app.colormapIndices;
            cm(2,:,:) = app.colormapIndices;
            
            colorIndexStart = ceil(startIndex1 * 256/size(app.MMP,2));
            colorIndexStart = min(colorIndexStart, 256);
            colorIndexEnd = ceil(endIndex1 * 256/size(app.MMP,2));
            colorIndexEnd = min(colorIndexEnd, 256);
            cm = cm(:,colorIndexStart:colorIndexEnd,:);
            
            y1 = zscore(app.data(startIndex1:endIndex1));
            y2 = zscore(app.data(startIndex2:endIndex2));
            ymin = min([y1,y2]);
            ymax = max([y1,y2]);
            
            cla(app.UIAxesMotifs);
            hold(app.UIAxesMotifs, 'on');
            imagesc(app.UIAxesMotifs,[1,size(app.MMP,2)],[ymin,ymax],cm);
            
            plot(app.UIAxesMotifs, y1,'k','LineWidth',1);
            plot(app.UIAxesMotifs, y2,'w','LineWidth',1);
            
            xlim(app.UIAxesMotifs, [1,subLength]);
            
            ylim(app.UIAxesMotifs, [ymin,ymax]);
            hold(app.UIAxesMotifs, 'off');
            
            app.plotTimeseries();
        end
    
        function plotTimeseries(app)
        
            if app.subsequenceOutlinePlot ~= 0
                delete(app.subsequenceOutlinePlot);
                delete(app.subsequenceColorPlot);
                delete(app.subsequenceMarkerOutlinePlot);
                delete(app.subsequenceMarkerColorPlot);
                delete(app.subsequenceOutlineNNPlot);
                delete(app.subsequenceColorNNPlot);
                delete(app.subsequenceMarkerOutlineNNPlot);
                delete(app.subsequenceMarkerColorNNPlot);
                
                delete(app.subsequenceZoomOutlinePlot);
                delete(app.subsequenceZoomColorPlot);
                delete(app.subsequenceZoomMarkerOutlinePlot);
                delete(app.subsequenceZoomMarkerColorPlot);
                delete(app.subsequenceZoomOutlineNNPlot);
                delete(app.subsequenceZoomColorNNPlot);
                delete(app.subsequenceZoomMarkerOutlineNNPlot);
                delete(app.subsequenceZoomMarkerColorNNPlot);
            end
            
            index1 = app.motifTimeIndex1;
            index2 = app.motifTimeIndex2;
            subLength = app.subLenSeries(app.motifSubLenIndex);
                        
            hold(app.UIAxesTimeseries,'on');
            
            if index1 > 0 && index2 > 0 && subLength > 0
                %the color points to the nearest neighbor, which is index2
                color2Index = ceil(index2/size(app.MMP,2)*size(app.colormapIndices,1));
                color2 = app.colormapIndices(color2Index,:);
                step = 1;
                X = index1:step:index1 + subLength;
                Y = app.data(index1:step:index1 + subLength);
                app.subsequenceMarkerOutlinePlot = plot(app.UIAxesTimeseries,[index1,index1],[min(app.data),max(app.data)],'Color',[0,0,0],'LineWidth',4);
                app.subsequenceMarkerColorPlot = plot(app.UIAxesTimeseries,[index1,index1],[min(app.data),max(app.data)],'Color',color2,'LineWidth',2);
                app.subsequenceOutlinePlot = plot(app.UIAxesTimeseries,X, Y,'Color',[0,0,0],'LineWidth',4);
                app.subsequenceColorPlot = plot(app.UIAxesTimeseries,X, Y,'Color',color2,'LineWidth',2);
                
                %the color points to the nearest neighbor, which is index1
                color1Index = ceil(index1/size(app.MMP,2)*size(app.colormapIndices,1));
                color1 = app.colormapIndices(color1Index,:);
                X = index2:step:index2 + subLength;
                Y = app.data(index2:step:index2 + subLength);
                app.subsequenceMarkerOutlineNNPlot = plot(app.UIAxesTimeseries,[index2,index2],[min(app.data),max(app.data)],'Color',[1,1,1],'LineWidth',4);
                app.subsequenceMarkerColorNNPlot = plot(app.UIAxesTimeseries,[index2,index2],[min(app.data),max(app.data)],'Color',color1,'LineWidth',2);
                app.subsequenceOutlineNNPlot = plot(app.UIAxesTimeseries,X, Y, 'Color',[1,1,1],'LineWidth',4);
                app.subsequenceColorNNPlot = plot(app.UIAxesTimeseries,X, Y, 'Color',color1,'LineWidth',2);
            end


            hold(app.UIAxesTimeseries,'off');
            
            %plot zoom exclusion zone
            zoomLen = app.endIndex - app.startIndex;
            app.plotZoomExclusionZone(app.centerIndex, zoomLen);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%
            %%% plot zoom subsequence
            %%%%%%%%%%%%%%%%%%%%%%%%%
            x = app.startIndex:app.endIndex;
            y = app.data(x);

            hold(app.UIAxesSubsequence,'on');

            if index1 > 0 && index2 > 0 && subLength > 0
                %the color points to the nearest neighbor, which is index2
                color2Index = ceil(index2/size(app.MMP,2)*size(app.colormapIndices,1));
                color2 = app.colormapIndices(color2Index,:);
                app.subsequenceZoomMarkerOutlinePlot = plot(app.UIAxesSubsequence,[index1,index1],[min(y),max(y)],'Color',[0,0,0],'LineWidth',4);
                app.subsequenceZoomMarkerColorPlot = plot(app.UIAxesSubsequence,[index1,index1],[min(y),max(y)],'Color',color2,'LineWidth',2);
                app.subsequenceZoomOutlinePlot = plot(app.UIAxesSubsequence,index1:index1 + subLength,app.data(index1:index1 + subLength),'Color',[0,0,0],'LineWidth',4);
                app.subsequenceZoomColorPlot = plot(app.UIAxesSubsequence,index1:index1 + subLength,app.data(index1:index1 + subLength),'Color',color2,'LineWidth',2);
                
                %the color points to the nearest neighbor, which is index1
                color1Index = ceil(index1/size(app.MMP,2)*size(app.colormapIndices,1));
                color1 = app.colormapIndices(color1Index,:);
                app.subsequenceZoomMarkerOutlineNNPlot = plot(app.UIAxesSubsequence,[index2,index2],[min(y),max(y)],'Color',[1,1,1],'LineWidth',4);
                app.subsequenceZoomMarkerColorNNPlot = plot(app.UIAxesSubsequence,[index2,index2],[min(y),max(y)],'Color',color1,'LineWidth',2);
                app.subsequenceZoomOutlineNNPlot = plot(app.UIAxesSubsequence,index2:index2 + subLength,app.data(index2:index2 + subLength),'Color',[1,1,1],'LineWidth',4);
                app.subsequenceZoomColorNNPlot = plot(app.UIAxesSubsequence,index2:index2 + subLength,app.data(index2:index2 + subLength),'Color',color1,'LineWidth',2);
            end
            xlim(app.UIAxesSubsequence, [app.startIndex,app.endIndex]);
            ylim(app.UIAxesSubsequence, [min(y), max(y)]);
            hold(app.UIAxesSubsequence,'off');
        end
        
    
        function plotNearestNeighborSubsequences(app, index1, index2, subLength)
            startIndex1 = index1;
            endIndex1 = startIndex1+subLength;
        
            startIndex2 = index2;
            endIndex2 = startIndex2 + subLength;
            
            plot(app.UIAxesMotifs,zscore(app.data(startIndex1:endIndex1)),'b');
            hold on;
            plot(app.UIAxesMotifs,zscore(app.data(startIndex2:endIndex2)),'r');
            hold off;
            xlim(app.UIAxesMotifs,[1,subLength]);
        end
        
        function displayCursorInfo(app)
            app.LabelSubLen.Text = sprintf('SubLen: %d',app.subLenSeries(app.motifSubLenIndex));
            app.LabelTimeIndex.Text = sprintf('TimeIndex: %d',app.motifTimeIndex1);
            app.LabelTimeIndexNN.Text = sprintf('TimeIndexNN: %d',app.motifTimeIndex2);
            app.LabelRawMMP.Text = sprintf('Raw MMP: %.2e',app.MMP(app.motifSubLenIndex,app.motifTimeIndex1));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, MMP, profileIndices, subLenSeries)
            
            app.CONTRAST_LOW = 1;
            app.CONTRAST_HIGH = 2;
            app.CONTRAST_BINARY = 3;
            app.CONTRAST_EXACT = 4;
            app.contrastMode = app.CONTRAST_LOW;
            app.HighContrastButton.Value = true;
            app.colormapDistances = gray();
            app.colormapIndices = colorcet('R1');
            
            app.showProfileIndicies = true;
            app.ProfileIndexOverlayCheckBox.Value = true;
            
            app.liveUpdateMotifs = false;
            app.LiveUpdateMotifsCheckBox.Value = false;
        
        
            if size(data,1) > 1
                data = data';
            end
            app.data = data;
            app.MMP = MMP;

            ps = linspace(0,100,11)';
            app.percentileValues = zeros(1,size(ps,1));

            for index=2:size(ps,1)
                app.percentileValues(index) = prctile(MMP,ps(index),'all');
            end
            
            
            app.profileIndices = profileIndices;
            app.subLenSeries = subLenSeries;
            
            app.maxMMP = max(max(app.MMP));
            app.minMMP = min(min(app.MMP));
            
            
            app.startIndex = 1;
            app.endIndex = ceil(length(app.data));
            app.centerIndex = ceil(length(app.data)/2);
            
            app.zoomExclusionPlotLeft = 0;
            app.zoomExclusionPlotRight = 0;
            
            app.surfaceCursorPlot = 0;
            app.surfaceCursorPosition = [length(app.data)/2, app.subLenSeries(end)/2, max(max(app.data)) + 1];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%  Plot Global and Zoom Timeseries
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.subsequenceOutlinePlot = 0;
            app.subsequenceColorPlot = 0;
            app.subsequenceMarkerOutlinePlot = 0;
            app.subsequenceMarkerColorPlot = 0;
            app.subsequenceOutlineNNPlot = 0;
            app.subsequenceColorNNPlot = 0;
            app.subsequenceMarkerOutlineNNPlot = 0;
            app.subsequenceMarkerColorNNPlot = 0;
            
            app.subsequenceZoomOutlinePlot = 0;
            app.subsequenceZoomColorPlot = 0;
            app.subsequenceZoomMarkerOutlinePlot = 0;
            app.subsequenceZoomMarkerColorPlot = 0;
            app.subsequenceZoomOutlineNNPlot = 0;
            app.subsequenceZoomColorNNPlot = 0;
            app.subsequenceZoomMarkerOutlineNNPlot = 0;
            app.subsequenceZoomMarkerColorNNPlot = 0;
            
            %colorbar
            cm = zeros(2,size(app.colormapIndices,1),3);
            cm(1,:,:) = app.colormapIndices;
            cm(2,:,:) = app.colormapIndices;
            
            hold(app.UIAxesTimeseries,'on');
            imagesc(app.UIAxesTimeseries,[1,size(app.MMP,2)],[min(app.data),max(app.data)],cm);            
            plot(app.UIAxesTimeseries,app.data,'Color',[1,1,1],'LineWidth',1);            
            xlim(app.UIAxesTimeseries,[1,length(app.data)]);
            ylim(app.UIAxesTimeseries, [min(app.data), max(app.data)]);            
            hold(app.UIAxesTimeseries,'off');
            
            hold(app.UIAxesSubsequence,'on');
            imagesc(app.UIAxesSubsequence,[1,size(app.MMP,2)],[min(app.data),max(app.data)],cm);
            plot(app.UIAxesSubsequence,app.data,'Color',[1,1,1],'LineWidth',1);
            xlim(app.UIAxesSubsequence,[1,length(app.data)]);
            ylim(app.UIAxesSubsequence, [min(app.data), max(app.data)]);
            hold(app.UIAxesSubsequence,'off');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%  Initialize Parameter Values
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %for thresholding, number of orders of magnitude to sweep over
            app.numOrders = min(5, abs(log10(app.minMMP)));
%             logMin = log10(max(1e-5,min(min(MMP))));
%             logMax = log10(max(max(MMP)));
%             logRange = logMax-logMin;
%             thresholdOrder = 0.1*logRange + logMin;
%             thresholdValue = power(10,thresholdOrder);
            thresholdValue = 0.01;
            app.ThreshEditField.Value = thresholdValue;
            app.ThresholdSlider.Value = thresholdValue;
%             app.centerOnThreshold();
            
            targetSubLen = app.subLenSeries(end)/2;
            distances = abs(app.subLenSeries - targetSubLen);
            indexSeries = 1:length(app.subLenSeries);
            app.motifSubLenIndex = min(indexSeries(distances == min(distances)));
            
            app.motifTimeIndex1 = ceil(length(data)/2);
            app.motifTimeIndex2 = app.profileIndices(app.motifSubLenIndex,app.motifTimeIndex1);
            
            app.displayCursorInfo();
            
            upperPowerLimit = log(size(app.MMP,2))/log(10);
            app.ZoomSlider.Limits = [2,upperPowerLimit];
            
            app.plotSurface();

            app.plotMotifs();
            app.plotTimeseries();
            
            colormap(app.UIAxesMMP,app.colormapDistances);
%             set(app.UIAxesMMP, 'YScale', 'log');
            ylim(app.UIAxesMMP,[subLenSeries(1),subLenSeries(end)]);


            disableDefaultInteractivity(app.UIAxesMotifs);
            disableDefaultInteractivity(app.UIAxesTimeseries);
            disableDefaultInteractivity(app.UIAxesSubsequence);
            disableDefaultInteractivity(app.UIAxesMMP);
            
        end

        % Value changed function: IndexSlider
        function IndexSliderValueChanged(app, event)
              app.updateSurfaceView();
              app.centerIndex = ceil(app.IndexSlider.Value*length(app.data));
              app.plotTimeseries();
        end

        % Value changed function: ZoomSlider
        function ZoomSliderValueChanged(app, event)
            zoomPower = app.ZoomSlider.Value;
           
            app.ZoomEditField.Value = power(10,zoomPower);
            app.updateSurfaceView();
            app.plotTimeseries();
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            app.ThreshEditField.Value = app.ThresholdSlider.Value;
            
            app.plotSurface();
        end

        % Value changed function: ThreshEditField
        function ThreshEditFieldValueChanged(app, event)
            app.ThresholdSlider.Value = app.ThreshEditField.Value;
            
            app.plotSurface();       
        end

        % Callback function
        function SelectPointButtonPushed(app, event)
            app.UIAxesMMP;
            [indeX,indexY] = ginput();
        end

        % Callback function
        function ZoomEditFieldValueChanged(app, event)
            zoomPower = app.ZoomEditField.Value;
            
            %update slider UI element position
            app.ZoomSlider.Value = log(zoomPower)/log(10);
            
            %update zoom window
            app.updateSurfaceView();
            app.plotTimeseries();
        end

        % Value changing function: ZoomSlider
        function ZoomSliderValueChanging(app, event)
            zoomPower = event.Value;
            
            
            %update editField with number
            subLen = ceil(power(10,zoomPower));
            app.ZoomEditField.Value = subLen;
            
            %update zoom visualization

            app.plotZoomExclusionZone(app.centerIndex, subLen);
%             app.plotTimeseries(0,0,0);

            startIndex  = max(ceil(app.centerIndex - subLen/2),1);
            endIndex    = min(ceil(app.centerIndex + subLen/2), length(app.data));

            if startIndex >= app.startIndex && endIndex <= app.endIndex
                xlim(app.UIAxesMMP,[startIndex,endIndex]);
            end
    
            
        end

        % Value changing function: IndexSlider
        function IndexSliderValueChanging(app, event)
            centerIndex = event.Value * length(app.data);
            subLen = app.endIndex - app.startIndex;
            app.displayCursorInfo();
            app.plotZoomExclusionZone(centerIndex, subLen);
        end

        % Value changing function: XSurfaceSlider
        function XSurfaceSliderValueChanging(app, event)
            timeseriesIndexRatio = event.Value;
            surfaceXRange = app.endIndex - app.startIndex;
            cursorOffset = timeseriesIndexRatio * surfaceXRange;
            timeseriesIndex = app.startIndex + cursorOffset;
            app.motifTimeIndex1 = ceil(timeseriesIndex);
            app.motifTimeIndex2 = app.profileIndices(app.motifSubLenIndex,app.motifTimeIndex1);
            
            app.displayCursorInfo();
            
            app.surfaceCursorPosition = [timeseriesIndex, app.surfaceCursorPosition(2),app.surfaceCursorPosition(3)];
            app.plotSurfaceCursor();
            
            if app.liveUpdateMotifs == true
                app.plotMotifs();
            end
        end

        % Value changed function: XSurfaceSlider
        function XSurfaceSliderValueChanged(app, event)
            timeseriesIndexRatio = app.XSurfaceSlider.Value;
            surfaceXRange = app.endIndex - app.startIndex;
            cursorOffset = timeseriesIndexRatio * surfaceXRange;
            timeseriesIndex = app.startIndex + cursorOffset;
            app.motifTimeIndex1 = ceil(timeseriesIndex);
            app.motifTimeIndex2 = app.profileIndices(app.motifSubLenIndex,app.motifTimeIndex1);
            
            app.displayCursorInfo();
                        
            
            app.surfaceCursorPosition = [timeseriesIndex, app.surfaceCursorPosition(2),app.surfaceCursorPosition(3)];
            app.plotSurfaceCursor();
            
            app.plotMotifs();
            
            app.plotColorbar();
        end

        % Value changing function: YSurfaceSlider
        function YSurfaceSliderValueChanging(app, event)
            sliderRatio = event.Value;
            minSublen = app.subLenSeries(1);
            maxSublen = app.subLenSeries(end);
            rangeSublen = maxSublen-minSublen;
            idealSubLen = rangeSublen*sliderRatio + minSublen;
            
            x = app.surfaceCursorPosition(1);
            y = idealSubLen;
            z = app.surfaceCursorPosition(3);
            
            app.surfaceCursorPosition = [x, y, z];
            app.plotSurfaceCursor();
            
            %%% calculate the index for the motif plot
            distances = abs(app.subLenSeries - idealSubLen);
            indexSeries = 1:length(app.subLenSeries);
            app.motifSubLenIndex = indexSeries(distances == min(distances));
            app.motifTimeIndex2 = app.profileIndices(app.motifSubLenIndex,app.motifTimeIndex1);
            
            app.displayCursorInfo();
            
            if app.liveUpdateMotifs == true
                app.plotMotifs();
            end
        end

        % Value changed function: YSurfaceSlider
        function YSurfaceSliderValueChanged(app, event)
            sliderRatio = event.Value;
            minSublen = app.subLenSeries(1);
            maxSublen = app.subLenSeries(end);
            rangeSublen = maxSublen-minSublen;
            idealSubLen = rangeSublen*sliderRatio + minSublen;
            
            x = app.surfaceCursorPosition(1);
            y = idealSubLen;
            z = app.surfaceCursorPosition(3);
            
            app.surfaceCursorPosition = [x, y, z];
            app.plotSurfaceCursor();
            
            %%% calculate the index for the motif plot
            distances = abs(app.subLenSeries - idealSubLen);
            indexSeries = 1:length(app.subLenSeries);
            app.motifSubLenIndex = min(indexSeries(distances == min(distances)));
            app.motifTimeIndex2 = app.profileIndices(app.motifSubLenIndex,app.motifTimeIndex1);
            
            app.displayCursorInfo();
            
            app.plotMotifs();
        end

        % Callback function
        function SavePlotMenuSelected(app, event)
            [saveName,path] = uiputfile()
            
%             h = figure;
%             h.Visible = 'off';
%             h = app.UIAxesMMP
%             x = app.UIAxesMMP.XAxis.Parent.Children.XData;
%             y = app.UIAxesMMP.XAxis.Parent.Children.YData;
%             plot(x,y)
%             lgndName1 = app.UIAxesMMP.Legend.String{1};
%             lgd = legend(lgndName1);
%             lgd.Box = app.UIAxesMMP.Legend.Box;
%             lgd.Location = app.UIAxesMMP.Legend.Location;
%             h.CurrentAxes.YLabel.String = app.UIAxesMMP.YLabel.String;
%             h.CurrentAxes.YLabel.FontSize = app.UIAxesMMP.YLabel.FontSize;
%             h.CurrentAxes.XLabel.String = app.UIAxesMMP.XLabel.String;
%             h.CurrentAxes.XLabel.FontSize = app.UIAxesMMP.XLabel.FontSize;
%             h.CurrentAxes.Title.String = app.UIAxesMMP.Title.String;
%             h.CurrentAxes.Title.FontSize = app.UIAxesMMP.Title.FontSize;
%             h.CurrentAxes.XLim = [0 max(x)];
%             h.CurrentAxes.XLim = [0 max(y)+1];
%             saveas(app.UIAxesMMP,saveName,'png');
%             savefig(h,saveName)
%             delete(h);
% Create new figure and new axes
            figure
            axNew = axes; 
            % Copy all objects from UIAxes to new axis
            copyobj(app.UIAxesMMP.Children, axNew)
            % Save all parameters of the UIAxes
            uiAxParams = get(app.UIAxesMMP);
            uiAxParamNames = fieldnames(uiAxParams); 
            % Get list of editable params in new axis
            editableParams = fieldnames(set(axNew)); 
            % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
            badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams)); 
            badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis';'Position';'OuterPosition']; 
            uiAxGoodParams = rmfield(uiAxParams,badFields); 
            % set editable params on new axes
            set(axNew, uiAxGoodParams)
            saveas(axNew,strcat(path,saveName),'png');
        end

        % Callback function
        function ButtonThresholdPlusPushed(app, event)
            incrementValue = 0.05;
            threshold = app.ThreshEditField.Value;
            threshold = threshold + incrementValue;
            app.ThreshEditField.Value = threshold;
            app.ThresholdSlider.Value = threshold;
            
            app.plotSurface();
        end

        % Callback function
        function ButtonThresholdMinusPushed(app, event)
            incrementValue = 0.05;
            threshold = app.ThreshEditField.Value;
            threshold = threshold - incrementValue;
            app.ThreshEditField.Value = threshold;
            app.ThresholdSlider.Value = threshold;
            
%             app.centerOnThreshold();
            app.plotSurface();
        end

        % Selection changed function: ContrastButtonGroup
        function ContrastButtonGroupSelectionChanged(app, event)
            selectedButton = app.ContrastButtonGroup.SelectedObject;
            buttonVector = ismember(app.ContrastButtonGroup.Buttons,app.ContrastButtonGroup.SelectedObject);
            buttonNumber = max(find(buttonVector));
            app.contrastMode = buttonNumber;
            
            app.plotSurface();
        end

        % Value changed function: ProfileIndexOverlayCheckBox
        function ProfileIndexOverlayCheckBoxValueChanged(app, event)
            value = app.ProfileIndexOverlayCheckBox.Value;
            app.showProfileIndicies = value;
            app.plotSurface();
        end

        % Value changed function: LiveUpdateMotifsCheckBox
        function LiveUpdateMotifsCheckBoxValueChanged(app, event)
            value = app.LiveUpdateMotifsCheckBox.Value;
            app.liveUpdateMotifs = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesMMP = uiaxes(app.UIFigure);
            xlabel(app.UIAxesMMP, 'Timeseries Index')
            ylabel(app.UIAxesMMP, 'Subsequence Length')
            app.XSurfaceSlider = uislider(app.UIFigure);
            app.XSurfaceSlider.Limits = [0 1];
            app.XSurfaceSlider.MajorTicks = [];
            app.XSurfaceSlider.ValueChangedFcn = createCallbackFcn(app, @XSurfaceSliderValueChanged, true);
            app.XSurfaceSlider.ValueChangingFcn = createCallbackFcn(app, @XSurfaceSliderValueChanging, true);
            app.XSurfaceSlider.MinorTicks = [];
            app.XSurfaceSlider.Value = 0.5;
            app.YSurfaceSlider = uislider(app.UIFigure);
            app.YSurfaceSlider.Limits = [0 1];
            app.YSurfaceSlider.MajorTicks = [];
            app.YSurfaceSlider.ValueChangedFcn = createCallbackFcn(app, @YSurfaceSliderValueChanged, true);
            app.YSurfaceSlider.ValueChangingFcn = createCallbackFcn(app, @YSurfaceSliderValueChanging, true);
            app.YSurfaceSlider.MinorTicks = [];
            app.YSurfaceSlider.Value = 0.5;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeseriesPlotsTab = uitab(app.TabGroup);
            app.TimeseriesPlotsTab.Title = 'Timeseries Plots';
            app.UIAxesSubsequence = uiaxes(app.TimeseriesPlotsTab);
            app.UIAxesSubsequence.XTick = [];
            app.UIAxesSubsequence.YTick = [];
            app.UIAxesMotifs = uiaxes(app.TimeseriesPlotsTab);
            app.UIAxesMotifs.XTick = [];
            app.UIAxesMotifs.YTick = [];
            app.ZoomSlider = uislider(app.TimeseriesPlotsTab);
            app.ZoomSlider.Limits = [2 7];
            app.ZoomSlider.MajorTicks = [];
            app.ZoomSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomSliderValueChanged, true);
            app.ZoomSlider.ValueChangingFcn = createCallbackFcn(app, @ZoomSliderValueChanging, true);
            app.ZoomSlider.MinorTicks = [];
            app.ZoomSlider.Value = 5;
            app.UIAxesTimeseries = uiaxes(app.TimeseriesPlotsTab);
            app.UIAxesTimeseries.YTick = [];
            app.IndexSlider = uislider(app.TimeseriesPlotsTab);
            app.IndexSlider.Limits = [0 1];
            app.IndexSlider.MajorTicks = [];
            app.IndexSlider.ValueChangedFcn = createCallbackFcn(app, @IndexSliderValueChanged, true);
            app.IndexSlider.ValueChangingFcn = createCallbackFcn(app, @IndexSliderValueChanging, true);
            app.IndexSlider.MinorTicks = [];
            app.IndexSlider.Value = 0.5;
            app.ZoomEditFieldLabel = uilabel(app.TimeseriesPlotsTab);
            app.ZoomEditFieldLabel.Text = 'Zoom';
            app.ZoomEditField = uieditfield(app.TimeseriesPlotsTab, 'numeric');
            app.LabelSubLen = uilabel(app.TimeseriesPlotsTab);
            app.LabelTimeIndex = uilabel(app.TimeseriesPlotsTab);
            app.LabelRawMMP = uilabel(app.TimeseriesPlotsTab);
            app.LabelTimeIndexNN = uilabel(app.TimeseriesPlotsTab);
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.ContrastButtonGroup = uibuttongroup(app.OptionsTab);
            app.ContrastButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ContrastButtonGroupSelectionChanged, true);
            app.ContrastButtonGroup.Title = 'Contrast';
            app.LowContrastButton = uiradiobutton(app.ContrastButtonGroup);
            app.LowContrastButton.Text = 'Low Contrast';
            app.LowContrastButton.Value = true;
            app.HighContrastButton = uiradiobutton(app.ContrastButtonGroup);
            app.HighContrastButton.Text = 'High Contrast';
            app.BinaryButton = uiradiobutton(app.ContrastButtonGroup);
            app.BinaryButton.Text = 'Binary';
            app.PefectMatchButton = uiradiobutton(app.ContrastButtonGroup);
            app.PefectMatchButton.Text = 'Pefect Match';
            app.ProfileIndexOverlayCheckBox = uicheckbox(app.OptionsTab);
            app.ProfileIndexOverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProfileIndexOverlayCheckBoxValueChanged, true);
            app.ProfileIndexOverlayCheckBox.Text = 'Profile Index Overlay';
            app.ThreshEditFieldLabel = uilabel(app.OptionsTab);
            app.ThreshEditFieldLabel.Text = 'Thresh';
            app.ThreshEditField = uieditfield(app.OptionsTab, 'numeric');
            app.ThreshEditField.Limits = [0 1];
            app.ThreshEditField.ValueChangedFcn = createCallbackFcn(app, @ThreshEditFieldValueChanged, true);
            app.ThreshEditField.Value = 0.33;
            app.ThresholdSlider = uislider(app.OptionsTab);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Value = 0.33;
            app.LiveUpdateMotifsCheckBox = uicheckbox(app.OptionsTab);
            app.LiveUpdateMotifsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveUpdateMotifsCheckBoxValueChanged, true);
            app.LiveUpdateMotifsCheckBox.Text = 'Live Update Motifs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualizeMMP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end