classdef visualizeMMPAB_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxesPMP                       matlab.ui.control.UIAxes
        XSurfaceSlider                  matlab.ui.control.Slider
        YSurfaceSlider                  matlab.ui.control.Slider
        TabGroup                        matlab.ui.container.TabGroup
        TimeseriesPlotsTab              matlab.ui.container.Tab
        UIAxesTimeSeriesPrimaryZoom     matlab.ui.control.UIAxes
        UIAxesMotifs                    matlab.ui.control.UIAxes
        ZoomSlider                      matlab.ui.control.Slider
        UIAxesTimeSeriesPrimary         matlab.ui.control.UIAxes
        ZoomIndexSlider                 matlab.ui.control.Slider
        ZoomEditFieldLabel              matlab.ui.control.Label
        ZoomEditField                   matlab.ui.control.NumericEditField
        LabelSubLen                     matlab.ui.control.Label
        LabelTimeIndex                  matlab.ui.control.Label
        LabelRawPMP                     matlab.ui.control.Label
        LabelTimeIndexNN                matlab.ui.control.Label
        UIAxesTimeSeriesSecondary       matlab.ui.control.UIAxes
        PrimaryLabel                    matlab.ui.control.Label
        SecondaryLabel                  matlab.ui.control.Label
        PrimaryZoomLabel                matlab.ui.control.Label
        JoinModeSwitch                  matlab.ui.control.RockerSwitch
        LocalColorButton                matlab.ui.control.StateButton
        ColormapStartIndexEditField     matlab.ui.control.NumericEditField
        ColormapEndIndexEditField       matlab.ui.control.NumericEditField
        JumpLeftButton                  matlab.ui.control.Button
        CenterCursorButton              matlab.ui.control.Button
        JumpRightButton                 matlab.ui.control.Button
        MotifSquishSlider               matlab.ui.control.Slider
        ContrastTab                     matlab.ui.container.Tab
        ContrastButtonGroup             matlab.ui.container.ButtonGroup
        LowContrastButton               matlab.ui.control.RadioButton
        HighContrastButton              matlab.ui.control.RadioButton
        BinaryButton                    matlab.ui.control.RadioButton
        PefectMatchButton               matlab.ui.control.RadioButton
        ProfileIndexOverlayCheckBox     matlab.ui.control.CheckBox
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdSlider                 matlab.ui.control.Slider
        PercentileEditFieldLabel        matlab.ui.control.Label
        PercentileEditField             matlab.ui.control.NumericEditField
        PercentileSlider                matlab.ui.control.Slider
        ContrastCenteringLabel          matlab.ui.control.Label
        ConfigTab                       matlab.ui.container.Tab
        UsesPalindromeCheckBox          matlab.ui.control.CheckBox
        LiveUpdateMotifsCheckBox        matlab.ui.control.CheckBox
        MaxParamValueEditFieldLabel     matlab.ui.control.Label
        MaxParamValueEditField          matlab.ui.control.NumericEditField
        MinParamValueEditFieldLabel     matlab.ui.control.Label
        MinParamValueEditField          matlab.ui.control.NumericEditField
        LogarithmicYaxisCheckBox        matlab.ui.control.CheckBox
        ScaleSweepCheckBox              matlab.ui.control.CheckBox
        DataBQueryfixedlengthCheckBox   matlab.ui.control.CheckBox
        DataBQueryfixedlengthEditFieldLabel  matlab.ui.control.Label
        DataBQueryfixedlengthEditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        %%%%%%%%%%%%%%%%%%%%%%%
        %%% Input Variables %%%
        %%%%%%%%%%%%%%%%%%%%%%%
        data;
        dataSecondary; %Used to support reversals/palindromes
        dataRowPrimary;
        dataRowSecondary;
        PMP;
        profileIndices;
        subLengthSeries;
        yAxis;
        title;
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% Input Variable Basic Properties %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        lengthData;
        lengthDataSecondary;
        minData;
        maxData;
        
        maxPMP;
        minPMP;
        
        numYValues;
        minYValue;
        maxYValue;
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% Input Variables Computed Properties %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        percentile;
        percentileValues;
        
        colormapDistances;
        colormapProfileIndices;
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% Dynamic Visualization Plots for Live Update %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        Primary_subsequenceOutlinePlot;
        Primary_subsequenceColorPlot;
        Primary_subsequenceMarkerOutlinePlot;
        Primary_subsequenceMarkerColorPlot;
        
        Secondary_subsequenceOutlinePlot;
        Secondary_subsequenceColorPlot;
        Secondary_subsequenceMarkerOutlinePlot;
        Secondary_subsequenceMarkerColorPlot;
        
        Primary_subsequenceZoomOutlinePlot;
        Primary_subsequenceZoomColorPlot;
        Primary_subsequenceZoomMarkerOutlinePlot;
        Primary_subsequenceZoomMarkerColorPlot;
        
%         subsequenceZoomOutlineNNPlot;
%         subsequenceZoomColorNNPlot;
%         subsequenceZoomMarkerOutlineNNPlot;
%         subsequenceZoomMarkerColorNNPlot;

        zoomExclusionPlotLeft;
        zoomExclusionPlotRight;
        
        surfaceCursorPrimary1;
        surfaceCursorPrimary2;
        surfaceCursorPrimary3;
        surfaceCursorSecondary1;
        surfaceCursorSecondary2;
        surfaceCursorSecondary3;

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% Visualization Misc Variables %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        timeSeriesColormap;
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% Visualization Global State %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            startIndex;
            endIndex;
            centerIndex;
        
        motifTimeIndex1;
        motifTimeIndex2;
        yAxisIndex;
        
        surfaceCursorPosition;
        
        colormapStartIndex;
        colormapEndIndex;
        
        %%%%%%%%%%%%%%%%
        %%% UI Modes %%%
        %%%%%%%%%%%%%%%%
        contrastMode;
        CONTRAST_LOW;
        CONTRAST_BINARY;
        CONTRAST_HIGH;
        CONTRAST_EXACT;
        
        showProfileIndicies;
        liveUpdateMotifs;
        
        logarithmicYAxis;
        
        useScaleSweep;
        
        
        %%%%%%%%%%%%%%%%%%%%%
        %%% UI Parameters %%%
        %%%%%%%%%%%%%%%%%%%%%
        counter;
        lastRefreshTime;
        timeSeriesFPS;
        
        primaryJoin;
        secondaryJoin;
        joinMode;
        
        consecutiveCenteringClick;
    end
    
    methods (Access = private)
        %%%%%%%%%%%%%%%%%%
        %%% UI Helpers %%%
        %%%%%%%%%%%%%%%%%%
        
        function answer = requestTimeSeriesRefresh(app)
            answer = false;
            t = seconds(days(now));
%             disp(app.timeSeriesFPS)
            if t-app.lastRefreshTime > 1/app.timeSeriesFPS
                app.lastRefreshTime = t;
                answer = true;
            end
        end
        
        function displayCursorInfo(app)
            app.LabelSubLen.Text = sprintf('Y Value: %d',app.yAxis(app.yAxisIndex));
            app.LabelTimeIndex.Text = sprintf('Primary Index: %d',app.motifTimeIndex1);
            app.LabelTimeIndexNN.Text = sprintf('Secondary Index: %d',app.motifTimeIndex2);
            app.LabelRawPMP.Text = sprintf('Raw PMP: %.2e',app.PMP{app.primaryJoin}(app.yAxisIndex,app.motifTimeIndex1));
        end
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%
        %%% Time Series Plots %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%
        function plotMotifs(app)
            subLength = app.subLengthSeries(app.yAxisIndex);
            
            if app.useScaleSweep == true
                subLengthNN = ceil(subLength * app.yAxis(app.yAxisIndex));
            elseif app.DataBQueryfixedlengthEditField.Value > 0
                subLengthNN = ceil(app.DataBQueryfixedlengthEditField.Value);
                fprintf("subLengthNN: %d\n",subLengthNN);
            else
                subLengthNN = subLength;
            end
%             fprintf("subLengthNN: %d\n",subLengthNN);
            startIndex1 = app.motifTimeIndex1;
            endIndex1 = startIndex1 + subLength-1;
            if ~isnan(app.PMP{app.primaryJoin}(app.yAxisIndex,startIndex1))
        
                startIndex2 = app.profileIndices{app.primaryJoin}(app.yAxisIndex, startIndex1);
                endIndex2 = startIndex2 + subLengthNN-1;
                endIndex2 = min(endIndex2, app.lengthDataSecondary{app.primaryJoin});
                
                
                cm = zeros(2,size(app.colormapProfileIndices,1),3);
                cm(1,:,:) = app.colormapProfileIndices;
                cm(2,:,:) = app.colormapProfileIndices;
                
                colorIndexStart = ceil(startIndex1 * 256/app.lengthData{app.primaryJoin});
                colorIndexStart = min(colorIndexStart, 256);
                colorIndexEnd = ceil(endIndex1 * 256/app.lengthData{app.primaryJoin});
                colorIndexEnd = min(colorIndexEnd, 256);
                cm = cm(:,colorIndexStart:colorIndexEnd,:);
                
                if size(app.data{app.primaryJoin},1) == 1
                    yAxisIndex = 1;
                else
                   yAxisIndex = app.yAxisIndex;
                end
                                
                y1 = zscore(app.data{app.primaryJoin}(yAxisIndex, startIndex1:endIndex1));
                y2 = zscore(app.dataSecondary{app.primaryJoin}(yAxisIndex, startIndex2:endIndex2));
                ymin = min([y1,y2]);
                ymax = max([y1,y2]);
                yRange = ymax - ymin;
                
                ymin = ymin - 0.1*yRange;
                ymax = ymax + 0.1*yRange;
                
                cla(app.UIAxesMotifs);
                hold(app.UIAxesMotifs, 'on');
                imagesc(app.UIAxesMotifs,[1,app.lengthData{app.primaryJoin}],[ymin,ymax],cm);
                
                plot(app.UIAxesMotifs,linspace(1, subLength,length(y1)), y1,'k','LineWidth',1);
                plot(app.UIAxesMotifs,linspace(1, subLength,length(y2)), y2,'w','LineWidth',1);
                
                squishLimit = ceil(subLength/app.MotifSquishSlider.Value);
                xlim(app.UIAxesMotifs, [1,squishLimit]);
                
                ylim(app.UIAxesMotifs, [ymin,ymax]);
                hold(app.UIAxesMotifs, 'off');
            else
                fprintf("nan error\n");
            end
            
        end
    
        function plotStaticTimeSeriesPrimary(app)
            cla(app.UIAxesTimeSeriesPrimary);
            
            if size(app.data{app.primaryJoin},1) == 1
               yAxisIndex = 1;
            else
               yAxisIndex = app.yAxisIndex;
            end
            
            hold(app.UIAxesTimeSeriesPrimary,'on');
            imagesc(app.UIAxesTimeSeriesPrimary,[1,app.lengthData{app.primaryJoin}],[app.minData{app.primaryJoin}(yAxisIndex),app.maxData{app.primaryJoin}(yAxisIndex)],app.timeSeriesColormap);            
            plot(app.UIAxesTimeSeriesPrimary,app.data{app.primaryJoin}(yAxisIndex,:),'Color',[1,1,1],'LineWidth',1);            
            xlim(app.UIAxesTimeSeriesPrimary,[1,app.lengthData{app.primaryJoin}]);
            ylim(app.UIAxesTimeSeriesPrimary, [app.minData{app.primaryJoin}(yAxisIndex), app.maxData{app.primaryJoin}(yAxisIndex)]);            
            hold(app.UIAxesTimeSeriesPrimary,'off');
%             app.updateDynamicTimeSeriesPrimary();
        end
        
        function plotStaticTimeSeriesSecondary(app)
            cla(app.UIAxesTimeSeriesSecondary);
            
            if size(app.dataSecondary{app.primaryJoin},1) == 1
               yAxisIndex = 1;
            else
               yAxisIndex = app.yAxisIndex;
            end
            
            hold(app.UIAxesTimeSeriesSecondary,'on');
            if app.LocalColorButton.Value == true

                startcm = zeros(2,size(app.colormapProfileIndices,1),3);
                startcm(1,:,:) = repmat(app.colormapProfileIndices(1,:),size(app.colormapProfileIndices,1),1);
                startcm(2,:,:) = repmat(app.colormapProfileIndices(1,:),size(app.colormapProfileIndices,1),1);
                
                endcm = zeros(2,size(app.colormapProfileIndices,1),3);
                endcm(1,:,:) = repmat(app.colormapProfileIndices(end,:),size(app.colormapProfileIndices,1),1);
                endcm(2,:,:) = repmat(app.colormapProfileIndices(end,:),size(app.colormapProfileIndices,1),1);

                imagesc(app.UIAxesTimeSeriesSecondary,[1,app.ColormapStartIndexEditField.Value],[app.minData{app.secondaryJoin}(yAxisIndex),app.maxData{app.secondaryJoin}(yAxisIndex)],startcm);  
                imagesc(app.UIAxesTimeSeriesSecondary,[app.ColormapStartIndexEditField.Value,app.ColormapEndIndexEditField.Value],[app.minData{app.secondaryJoin}(yAxisIndex),app.maxData{app.secondaryJoin}(yAxisIndex)],app.timeSeriesColormap);
                imagesc(app.UIAxesTimeSeriesSecondary,[app.ColormapEndIndexEditField.Value,app.lengthDataSecondary{app.primaryJoin}],[app.minData{app.secondaryJoin}(yAxisIndex),app.maxData{app.secondaryJoin}(yAxisIndex)],endcm);
            else
                imagesc(app.UIAxesTimeSeriesSecondary,[1,app.lengthData{app.secondaryJoin}],[app.minData{app.secondaryJoin}(yAxisIndex),app.maxData{app.secondaryJoin}(yAxisIndex)],app.timeSeriesColormap);  
            end
            plot(app.UIAxesTimeSeriesSecondary,app.dataSecondary{app.primaryJoin}(yAxisIndex,:),'Color',[1,1,1],'LineWidth',1);            
            xlim(app.UIAxesTimeSeriesSecondary,[1,app.lengthDataSecondary{app.primaryJoin}]);
            ylim(app.UIAxesTimeSeriesSecondary, [app.minData{app.secondaryJoin}(yAxisIndex),app.maxData{app.secondaryJoin}(yAxisIndex)]);            
            hold(app.UIAxesTimeSeriesSecondary,'off');
%             app.updateDynamicTimeSeriesSecondary();
        end
        
        function plotStaticTimeSeriesPrimaryZoomed(app)
            cla(app.UIAxesTimeSeriesPrimaryZoom);
            
            if size(app.data{app.primaryJoin},1) == 1
               yAxisIndex = 1;
            else
               yAxisIndex = app.yAxisIndex;
            end
            
            hold(app.UIAxesTimeSeriesPrimaryZoom,'on');
            imagesc(app.UIAxesTimeSeriesPrimaryZoom,[1,app.lengthData{app.primaryJoin}],[app.minData{app.primaryJoin}(yAxisIndex),app.maxData{app.primaryJoin}(yAxisIndex)],app.timeSeriesColormap);
            plot(app.UIAxesTimeSeriesPrimaryZoom,app.data{app.primaryJoin}(yAxisIndex,:),'Color',[1,1,1],'LineWidth',1);
            xlim(app.UIAxesTimeSeriesPrimaryZoom,[1,app.lengthData{app.primaryJoin}]);
            ylim(app.UIAxesTimeSeriesPrimaryZoom, [app.minData{app.primaryJoin}(yAxisIndex),app.maxData{app.primaryJoin}(yAxisIndex)]);
            hold(app.UIAxesTimeSeriesPrimaryZoom,'off');
%             app.updateDynamicTimeSeriesPrimaryZoomed();
        end
        
        function plotAllStaticTimeSeries(app)
            plotStaticTimeSeriesPrimary(app);
            plotStaticTimeSeriesSecondary(app);
            plotStaticTimeSeriesPrimaryZoomed(app);
        end
        
        function updateDynamicTimeSeriesPrimary(app)
            if app.Primary_subsequenceOutlinePlot ~= 0
                delete(app.Primary_subsequenceOutlinePlot);
                delete(app.Primary_subsequenceColorPlot);
                delete(app.Primary_subsequenceMarkerOutlinePlot);
                delete(app.Primary_subsequenceMarkerColorPlot);
                
                delete(app.Primary_subsequenceZoomOutlinePlot);
                delete(app.Primary_subsequenceZoomColorPlot);
                delete(app.Primary_subsequenceZoomMarkerOutlinePlot);
                delete(app.Primary_subsequenceZoomMarkerColorPlot);
            end
           
            index1 = app.motifTimeIndex1;
            index2 = app.motifTimeIndex2;
            subLength = app.subLengthSeries(app.yAxisIndex);
                        
            if ~isnan(app.PMP{app.primaryJoin}(app.yAxisIndex,index1))
                if index1 > 0 && index2 > 0 && subLength > 0
                    %the color points to the nearest neighbor, which is index2
                    color2Index = app.getColormapIndex(index2, app.secondaryJoin);
                    color2 = app.colormapProfileIndices(color2Index,:);
                    indexStep = 1;
                    X = index1:indexStep:index1 + subLength - 1;
                    
                    if size(app.data{app.primaryJoin},1) == 1
                       yAxisIndex = 1;
                    else
                       yAxisIndex = app.yAxisIndex;
                    end
                    
                    Y = app.data{app.primaryJoin}(yAxisIndex,index1:indexStep:index1 + subLength - 1);
                    hold(app.UIAxesTimeSeriesPrimary,'on');
                    app.Primary_subsequenceMarkerOutlinePlot = plot(app.UIAxesTimeSeriesPrimary,[index1,index1],[app.minData{app.primaryJoin}(yAxisIndex),app.maxData{app.primaryJoin}(yAxisIndex)],'Color',[0,0,0],'LineWidth',4);
                    app.Primary_subsequenceMarkerColorPlot = plot(app.UIAxesTimeSeriesPrimary,[index1,index1],[app.minData{app.primaryJoin}(yAxisIndex),app.maxData{app.primaryJoin}(yAxisIndex)],'Color',color2,'LineWidth',2);
                    app.Primary_subsequenceOutlinePlot = plot(app.UIAxesTimeSeriesPrimary,X, Y,'Color',[0,0,0],'LineWidth',4);
                    app.Primary_subsequenceColorPlot = plot(app.UIAxesTimeSeriesPrimary,X, Y,'Color',color2,'LineWidth',2);
                    hold(app.UIAxesTimeSeriesPrimary,'off');
                end
            end
        end
        
        function updateDynamicTimeSeriesSecondary(app)
        
            if app.Secondary_subsequenceOutlinePlot ~= 0
                delete(app.Secondary_subsequenceOutlinePlot);
                delete(app.Secondary_subsequenceColorPlot);
                delete(app.Secondary_subsequenceMarkerOutlinePlot);
                delete(app.Secondary_subsequenceMarkerColorPlot);
            end
            
            index1 = app.motifTimeIndex1;
            index2 = app.motifTimeIndex2;
            
            
            if app.useScaleSweep == true
                subLength = app.subLengthSeries(app.yAxisIndex);
                subLength = ceil(subLength * app.yAxis(app.yAxisIndex));
            elseif app.DataBQueryfixedlengthEditField.Value > 0
                subLength = ceil(app.DataBQueryfixedlengthEditField.Value);
            else
                subLength = app.subLengthSeries(app.yAxisIndex);
            end
                     
            if ~isnan(app.PMP{app.primaryJoin}(app.yAxisIndex,index1))
                if index1 > 0 && index2 > 0 && subLength > 0
                    %the color points to the nearest neighbor, which is index1
                    color1Index = app.getColormapIndex(index1, app.primaryJoin);
                    color1 = app.colormapProfileIndices(color1Index,:);
                    indexStep = 1;
                    X = index2:indexStep:index2 + subLength - 1;
                    
                    if size(app.dataSecondary{app.primaryJoin},1) == 1
                       yAxisIndex = 1;
                    else
                       yAxisIndex = app.yAxisIndex;
                    end
                    
                    Y = app.dataSecondary{app.primaryJoin}(yAxisIndex, index2:indexStep:index2 + subLength-1);
                    hold(app.UIAxesTimeSeriesSecondary,'on');
                    app.Secondary_subsequenceMarkerOutlinePlot = plot(app.UIAxesTimeSeriesSecondary,[index2,index2],[app.minData{app.secondaryJoin}(yAxisIndex),app.maxData{app.secondaryJoin}(yAxisIndex)],'Color',[1,1,1],'LineWidth',4);
                    app.Secondary_subsequenceMarkerColorPlot = plot(app.UIAxesTimeSeriesSecondary,[index2,index2],[app.minData{app.secondaryJoin}(yAxisIndex),app.maxData{app.secondaryJoin}(yAxisIndex)],'Color',color1,'LineWidth',2);
                    app.Secondary_subsequenceOutlinePlot = plot(app.UIAxesTimeSeriesSecondary,X, Y, 'Color',[1,1,1],'LineWidth',4);
                    app.Secondary_subsequenceColorPlot = plot(app.UIAxesTimeSeriesSecondary,X, Y, 'Color',color1,'LineWidth',2);
                    hold(app.UIAxesTimeSeriesSecondary,'off');
                end
            end
        end
        
        
        function updateDynamicTimeSeriesPrimaryZoomed(app)
            x = app.startIndex{app.primaryJoin}:app.endIndex{app.primaryJoin};
            y = app.data{app.primaryJoin}(x);
            
            index1 = app.motifTimeIndex1;
            index2 = app.motifTimeIndex2;
            subLength = app.subLengthSeries(app.yAxisIndex);
            
            %plot zoom exclusion zone
            zoomLen = app.endIndex{app.primaryJoin} - app.startIndex{app.primaryJoin};
            app.updateZoomExclusionZone(app.centerIndex{app.primaryJoin}, zoomLen);

            if ~isnan(app.PMP{app.primaryJoin}(app.yAxisIndex,index1)) 
                hold(app.UIAxesTimeSeriesPrimaryZoom,'on');
                
                if index1 > 0 && index2 > 0 && subLength > 0
                    if size(app.data{app.primaryJoin},1) == 1
                       yAxisIndex = 1;
                    else
                       yAxisIndex = app.yAxisIndex;
                    end
                    %the color points to the nearest neighbor, which is index2
                    color2Index = app.getColormapIndex(index2, app.primaryJoin);
                    color2 = app.colormapProfileIndices(color2Index,:);
                    app.Primary_subsequenceZoomMarkerOutlinePlot = plot(app.UIAxesTimeSeriesPrimaryZoom,[index1,index1],[min(y),max(y)],'Color',[0,0,0],'LineWidth',4);
                    app.Primary_subsequenceZoomMarkerColorPlot = plot(app.UIAxesTimeSeriesPrimaryZoom,[index1,index1],[min(y),max(y)],'Color',color2,'LineWidth',2);
                    app.Primary_subsequenceZoomOutlinePlot = plot(app.UIAxesTimeSeriesPrimaryZoom,index1:index1 + subLength - 1,app.data{app.primaryJoin}(yAxisIndex, index1:index1 + subLength - 1),'Color',[0,0,0],'LineWidth',4);
                    app.Primary_subsequenceZoomColorPlot = plot(app.UIAxesTimeSeriesPrimaryZoom,index1:index1 + subLength - 1,app.data{app.primaryJoin}(yAxisIndex, index1:index1 + subLength - 1),'Color',color2,'LineWidth',2);
                    
    %                 %the color points to the nearest neighbor, which is index1
    %                 color1Index = ceil(index1/app.lengthData{app.primaryJoin}*size(app.colormapProfileIndices,1));
    %                 color1 = app.colormapProfileIndices(color1Index,:);
    %                 app.subsequenceZoomMarkerOutlineNNPlot = plot(app.UIAxesTimeSeriesPrimaryZoom,[index2,index2],[min(y),max(y)],'Color',[1,1,1],'LineWidth',4);
    %                 app.subsequenceZoomMarkerColorNNPlot = plot(app.UIAxesTimeSeriesPrimaryZoom,[index2,index2],[min(y),max(y)],'Color',color1,'LineWidth',2);
    %                 app.subsequenceZoomOutlineNNPlot = plot(app.UIAxesTimeSeriesPrimaryZoom,index2:index2 + subLength - 1,app.data{app.primaryJoin}(index2:index2 + subLength - 1),'Color',[1,1,1],'LineWidth',4);
    %                 app.subsequenceZoomColorNNPlot = plot(app.UIAxesTimeSeriesPrimaryZoom,index2:index2 + subLength - 1,app.data{app.primaryJoin}(index2:index2 + subLength - 1),'Color',color1,'LineWidth',2);
                end
                xlim(app.UIAxesTimeSeriesPrimaryZoom, [app.startIndex{app.primaryJoin},app.endIndex{app.primaryJoin}]);
                ylim(app.UIAxesTimeSeriesPrimaryZoom, [min(y), max(y)]);
                hold(app.UIAxesTimeSeriesPrimaryZoom,'off');
            end
        end
        
        function updateZoomExclusionZone(app, centerIndex, subLen)
            startIndex  = max(ceil(centerIndex - subLen/2),1);
            endIndex    = min(ceil(centerIndex + subLen/2), app.lengthData{app.primaryJoin});
            
            if app.zoomExclusionPlotLeft ~= 0
                delete(app.zoomExclusionPlotLeft);
            end
            if app.zoomExclusionPlotRight ~= 0
                delete(app.zoomExclusionPlotRight);
            end
            
            if size(app.data{app.primaryJoin},1) == 1
               yAxisIndex = 1;
            else
               yAxisIndex = app.yAxisIndex;
            end
            
            rectHeight = app.maxData{app.primaryJoin}(yAxisIndex) - app.minData{app.primaryJoin}(yAxisIndex);
            if startIndex > 1
%                 app.zoomExclusionPlotLeft = rectangle(app.UIAxesTimeSeriesPrimary, 'Position', [1,min(app.data{app.primaryJoin}),startIndex,rectHeight],'FaceColor',[0.8 0.8 0.8]);
                x1 = [1, startIndex, startIndex, 1];
                y1 = [app.minData{app.primaryJoin}(yAxisIndex), app.minData{app.primaryJoin}(yAxisIndex), app.maxData{app.primaryJoin}(yAxisIndex), app.maxData{app.primaryJoin}(yAxisIndex)];
                app.zoomExclusionPlotLeft = patch(app.UIAxesTimeSeriesPrimary, x1,y1,'black','FaceAlpha',0.2);

            end
            
            if endIndex < app.lengthData{app.primaryJoin}
                x2 = [endIndex, app.lengthData{app.primaryJoin}, app.lengthData{app.primaryJoin}, endIndex];
                y2 = [app.minData{app.primaryJoin}(yAxisIndex), app.minData{app.primaryJoin}(yAxisIndex), app.maxData{app.primaryJoin}(yAxisIndex), app.maxData{app.primaryJoin}(yAxisIndex)];
                app.zoomExclusionPlotRight = patch(app.UIAxesTimeSeriesPrimary, x2,y2,'black','FaceAlpha',0.2);
            end
            
%             app.zoomExclusionPlotLeft.FaceColorAlpha = .2;
%             uistack(app.zoomExclusionPlotRight, 'bottom');
        end
        
        function updateAllDynamicTimeSeries(app)
            app.plotMotifs();
            app.updateDynamicTimeSeriesPrimary();          
            app.updateDynamicTimeSeriesSecondary();
            app.updateDynamicTimeSeriesPrimaryZoomed();
        end
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% Pan Matrix Profile (PMP) %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function idx = getIndexFromIdealYValue(app, idealYValue)
            [~,idx] = min(abs(app.yAxis - idealYValue));
        end
        
        function thresholdValue = getThresholdFromPercentile(app, percentileValue)
            if size(app.data{app.primaryJoin},1) == 1
               yAxisIndex = 1;
            else
               yAxisIndex = app.yAxisIndex;
            end
            thresholdValue = prctile(app.PMP{app.primaryJoin},percentileValue,'all');
%             thresholdValue = min(app.maxData{app.primaryJoin}(yAxisIndex), thresholdValue);
%             thresholdValue = 0.5;
        end
        
        function percentile = getPercentileFromThreshold(app, threshold)
            %%% exact O(n);
            percentile = 100*sum(app.PMP{app.primaryJoin} <= threshold,'all')/(app.lengthData{app.primaryJoin}*app.numYValues);
            percentile = min(100, percentile);
            
            %%% approx O(1);
            %%% linear interpolation of percentile value
            %%% x is percentile
            %%% y is PMP value
            %%% I want to find a percentile given a PMP value;
%             y = threshold;
%             x1 = find(app.percentileValues{app.primaryJoin}<=threshold,1,"last"); 
%             y1 = app.percentileValues{app.primaryJoin}(x1);
%             x2 = find(app.percentileValues{app.primaryJoin}>threshold,1,"first"); 
%             y2 = app.percentileValues{app.primaryJoin}(x2);
%             x1Prctile = (x1-1)/(length(app.percentileValues{app.primaryJoin})-1)*100;%subtract 1 because 1st index is 0;
%             x2Prctile = (x2-1)/(length(app.percentileValues{app.primaryJoin})-1)*100;
%             
%             tempRatio = (y-y1)/(y2-y1);
%             percentile  = tempRatio*(x2Prctile-x1Prctile)+x1Prctile;
%             disp(app.percentileValues{app.primaryJoin});
%             fprintf('x1: %d, x1Prctile: %d, x2: %d, x2Prctile: %d\n', x1, x2, x1Prctile, x2Prctile);
% 
%             fprintf('y1: %d, y2: %d, y: %d, approxPercentile: %d\n', y1, y2, y, percentile);
        end
        
        function syncContrastUI(app, thresholdValue, percentileValue)
            app.ThresholdEditField.Value = thresholdValue;
            app.ThresholdSlider.Value = thresholdValue;
            app.PercentileEditField.Value = percentileValue;
            app.PercentileSlider.Value = percentileValue;
        end
        
        function canvas = mapThresholdAs50Percent(app, canvas, threshold)
            indices = canvas <= threshold;
            canvas(indices) = normalize(canvas(indices),'range');
            canvas(indices) = canvas(indices)./2;

            indices = ~indices;
            canvas(indices) = normalize(canvas(indices),'range');
            canvas(indices) = canvas(indices)./2 + 0.5;
        end
        
        function percentileCanvas = centerOnThreshold(app, PMPCrop, percentileCanvas)
            percentile = app.PercentileEditField.Value/100;

            if app.contrastMode == app.CONTRAST_HIGH
%                 percentileCanvas = 1./(1+exp(-10.*(percentileCanvas-percentile)));
%                 percentileCanvas = min(1, max(0, percentileCanvas));
% d
                factor = 10;
                threshold = percentile;
                percentileCanvas = min(1, max(0, factor*(percentileCanvas-threshold) + threshold));
            elseif app.contrastMode == app.CONTRAST_BINARY
                
                indices = percentileCanvas <= percentile;
                percentileCanvas(indices) = 0;
                percentileCanvas(~indices) = 1;
                
            elseif app.contrastMode == app.CONTRAST_EXACT
                indices = PMPCrop <= 1e-10;
                percentileCanvas(indices) = 0;
                percentileCanvas(~indices) = 1;
                
            elseif app.contrastMode == app.CONTRAST_LOW
                percentileCanvas = app.mapThresholdAs50Percent(percentileCanvas, percentile);
            end            
        end
        
        function plotColorbar(app)
            if app.showProfileIndicies == true
                cursorColorIndex = app.getColormapIndex(app.motifTimeIndex2, app.primaryJoin);
                cursorColorIndex = min(256, cursorColorIndex);
                cursorColor = app.colormapProfileIndices(cursorColorIndex,:);
                cursorColormap = app.colormapDistances(1:8:end,:);
                cursorColormap = (1-cursorColormap) .* (1-cursorColor);

                cursorColormap = 1-cursorColormap;
                colormap(app.UIAxesPMP, cursorColormap);
                cbh = colorbar(app.UIAxesPMP);
                set(cbh,'YTick',[0,1]);
            else
                colormap(app.UIAxesPMP, app.colormapDistances);
                cbh = colorbar(app.UIAxesPMP);
            end
        end
        
        function colorIndices = getColormapIndex(app,queryIndices, joinMode)
            numColors = size(app.colormapProfileIndices,1);
            startIndex = app.colormapStartIndex{joinMode};
            endIndex = app.colormapEndIndex{joinMode};
            
            
            if app.LocalColorButton.Value == true
                targetRange = endIndex - startIndex + 1;
                colorRatios = (queryIndices-startIndex)/targetRange; %TODO Exactly align startIndex=1 endIndex=256;
                colorIndices = ceil(colorRatios*numColors);
                colorIndices = max(1,colorIndices);
                colorIndices = min(256, colorIndices);
            else
                
%                 fprintf("queryIndices: %d, lengthDataSeconary: %d, numColors: %d\n", queryIndices, app.lengthDataSecondary{joinMode}, numColors);
                colorIndices = ceil(queryIndices/(app.lengthDataSecondary{joinMode})*numColors);
          
            end
            colorIndices = min(colorIndices, 256);
            colorIndices = max(colorIndices, 1);
        end
        
        function plotSurfaceCursor(app)
            
            xIndex = app.surfaceCursorPosition{app.primaryJoin}(1);
            yIndex = app.surfaceCursorPosition{app.primaryJoin}(2);
            zIndex = app.surfaceCursorPosition{app.primaryJoin}(3);
            
            xIndexNN = app.motifTimeIndex2;
            if app.surfaceCursorPrimary1 ~= 0, delete(app.surfaceCursorPrimary1),end
            if app.surfaceCursorPrimary2 ~= 0, delete(app.surfaceCursorPrimary2),end
            if app.surfaceCursorPrimary3 ~= 0, delete(app.surfaceCursorPrimary3),end
            if app.surfaceCursorSecondary1 ~= 0, delete(app.surfaceCursorSecondary1),end
            if app.surfaceCursorSecondary2 ~= 0, delete(app.surfaceCursorSecondary2),end
            if app.surfaceCursorSecondary3 ~= 0, delete(app.surfaceCursorSecondary3),end
            
            hold(app.UIAxesPMP, 'on');
            yAxisIndex = app.getIndexFromIdealYValue(yIndex);
            if ~isnan(app.PMP{app.primaryJoin}(yAxisIndex,ceil(xIndex)))            
                colorIndex = app.getColormapIndex(xIndexNN, app.primaryJoin);
                markerColor = app.colormapProfileIndices(colorIndex,:);
                
                colorIndexNN = app.getColormapIndex(xIndex, app.secondaryJoin);
                markerColorNN = app.colormapProfileIndices(colorIndexNN,:);
                
                if app.logarithmicYAxis == true
                    yRange = log10(app.maxYValue(end)) - log10(app.minYValue(1)+1);
%                     yIndex = log10(yIndex);
%                     disp(yIndex);
                else
                    yRange = app.maxYValue(end) - app.minYValue(1)+1;
                end
                app.surfaceCursorPrimary1 = plot(app.UIAxesPMP,xIndex, yIndex,'o','MarkerSize',25,'LineWidth',5,'MarkerEdgeColor',markerColor);
                app.surfaceCursorPrimary2 = plot(app.UIAxesPMP,xIndex, yIndex,'+','MarkerSize',20,'LineWidth',1,'MarkerEdgeColor','k');
                app.surfaceCursorPrimary3 = plot(app.UIAxesPMP,xIndex, yIndex,'o','MarkerSize',20,'LineWidth',2,'MarkerEdgeColor','k');
                
                if strcmp(app.joinMode, "AA") == true
                    app.surfaceCursorSecondary1 = plot(app.UIAxesPMP,xIndexNN, yIndex,'o','MarkerSize',25,'LineWidth',5,'MarkerEdgeColor',markerColorNN);
                    app.surfaceCursorSecondary2 = plot(app.UIAxesPMP,xIndexNN, yIndex,'+','MarkerSize',20,'LineWidth',1,'MarkerEdgeColor','k');
                    app.surfaceCursorSecondary3 = plot(app.UIAxesPMP,xIndexNN, yIndex,'o','MarkerSize',20,'LineWidth',2,'MarkerEdgeColor','w');
                end
            else
                app.surfaceCursorSecondary1 = plot(app.UIAxesPMP, xIndex, yIndex,'rx','MarkerSize',20, 'LineWidth',5);
            end
            
            hold(app.UIAxesPMP, 'off');
        
        end
        
        function plotSurface(app)
        cla(app.UIAxesPMP);
            numPlotPixels = 1024;
            indexStep = 1;
            if app.endIndex{app.primaryJoin} - app.startIndex{app.primaryJoin} >= numPlotPixels
                indexStep = floor((app.endIndex{app.primaryJoin}-app.startIndex{app.primaryJoin})/numPlotPixels);
            end
            
            plotIndices = app.startIndex{app.primaryJoin}:indexStep:app.endIndex{app.primaryJoin};
            PMPCrop = app.PMP{app.primaryJoin}(:,plotIndices);
            canvasShade = PMPCrop;
            
            nanIndices = isnan(canvasShade);
            canvasShade(nanIndices) = 1;
            nanIndices = app.profileIndices{app.primaryJoin}(:,plotIndices) == 0;
            canvasShade(nanIndices) = 1;
            
           %%%adjust contrast
%             percentileCanvas = canvasShade;
%             numPercentiles = size(app.percentileValues{app.primaryJoin},2);
%             for index=2:numPercentiles
%                 selection = app.percentileValues{app.primaryJoin}(index-1) < canvasShade & canvasShade <= app.percentileValues{app.primaryJoin}(index);
%                 tempCanvas = canvasShade(selection);
%                 tempCanvas = normalize(tempCanvas,'range');
%                 percentileCanvas(selection) = tempCanvas*(1/(numPercentiles-1)) + (index-2)/(numPercentiles-1);
%             end

            %%%Assign values between 0 to 1 based on sorted order
            flattenedCanvasShade = reshape(canvasShade,[numel(canvasShade),1]);
            [~,sortedIndices] = sort(flattenedCanvasShade);
            flattenedPercentileCanvas = flattenedCanvasShade;
            flattenedPercentileCanvas(sortedIndices) = 1:length(flattenedPercentileCanvas);
            percentileCanvas = reshape(flattenedPercentileCanvas,size(canvasShade))./numel(canvasShade);

            canvasShade = app.centerOnThreshold(PMPCrop,percentileCanvas);

            
%           %%% Profile Indices with Color
            if app.showProfileIndicies == true
                profileIndices = app.profileIndices{app.primaryJoin}(:,plotIndices);
                canvas = zeros(size(canvasShade,1),size(canvasShade,2),3);
                for i=1:app.numYValues

                    indices = profileIndices(i,:);

                    colorIndices = app.getColormapIndex(indices, app.primaryJoin);
%                         indices = ceil(indices);
                    
                    colorIndices = min(colorIndices,256);
                    
                    colorIndices(colorIndices==0) = 256;
                    canvas(i,:,:) = app.colormapProfileIndices(colorIndices,:);
                end
                canvas = 1-canvas;
                canvas = canvas.*(1-canvasShade);
                canvas = 1-canvas;
            else
                canvas = canvasShade;
                colormap(app.UIAxesPMP,app.colormapDistances);
            end
            
            
            for i=1:length(app.yAxis)-1
                hold(app.UIAxesPMP, 'on');
                X = [plotIndices(1),plotIndices(end)];
                Y = app.yAxis(i:i+1);
                C = canvas(i:i+1,:,:);
                imagesc(app.UIAxesPMP,'XData',X,'YData',Y,'CData',C);
            end
            
            hold(app.UIAxesPMP, 'off');
            
            ymin = app.minYValue;
            ymax = app.maxYValue;
            
            xlim(app.UIAxesPMP,[app.startIndex{app.primaryJoin},app.endIndex{app.primaryJoin}]);
            ylim(app.UIAxesPMP,[app.minYValue,app.maxYValue]);
            app.UIAxesPMP.YDir = 'normal';
            
            if app.logarithmicYAxis == true
                set(app.UIAxesPMP, 'YScale', 'log');
            else
                set(app.UIAxesPMP, 'YScale', 'linear');
            end

%             app.plotColorbar();
            
            app.plotSurfaceCursor();
        end
        
        function plotPMP(app)
            centerIndexRatio = app.ZoomIndexSlider.Value;
          
            centerIndex = ceil(centerIndexRatio * app.lengthData{app.primaryJoin});
            range = ceil(power(10,app.ZoomSlider.Value));
            range = min(app.lengthData{app.primaryJoin}, range);
            startIndex = max(ceil(centerIndex - ceil(range/2)), 1);
            startIndex = ceil(startIndex);
            endIndex = min(ceil(centerIndex + ceil(range/2)), app.lengthData{app.primaryJoin});
            endIndex = ceil(endIndex);
            if endIndex < range
                startIndex = 1;
                endIndex = min(range, app.lengthData{app.primaryJoin});
            end
            if startIndex > app.lengthData{app.primaryJoin}-range + 1
                startIndex = max(app.lengthData{app.primaryJoin}-range + 1, 1);
                endIndex = app.lengthData{app.primaryJoin};
            end
            app.startIndex{app.primaryJoin} = ceil(startIndex);
            app.endIndex{app.primaryJoin} = ceil(endIndex);
            xlim(app.UIAxesPMP, [app.startIndex{app.primaryJoin}, app.endIndex{app.primaryJoin}]);
            app.plotSurface();
        end
        
        function switchUIForJoinMode(app)
            app.ThresholdSlider.Limits = [app.minPMP{app.primaryJoin}, app.maxPMP{app.primaryJoin}];
        end
        
        function switchJoinMode(app)
            app.plotAllStaticTimeSeries();
            app.updateAllDynamicTimeSeries();
            app.plotPMP();
            app.switchUIForJoinMode();
            
            if app.primaryJoin == 1
                app.PrimaryLabel.Text = "A";
                app.SecondaryLabel.Text = "B";
                app.PrimaryZoomLabel.Text = "A Zoom";
            else
                app.PrimaryLabel.Text = "B";
                app.SecondaryLabel.Text = "A";
                app.PrimaryZoomLabel.Text = "B Zoom";
            end
        end
        
        function updatePalindromeSettings(app, usesPalindrome)
            if usesPalindrome == true
                app.dataSecondary{app.primaryJoin} = [app.data{app.secondaryJoin},app.data{app.secondaryJoin}(:,end:-1:1)];
                app.dataSecondary{app.secondaryJoin} = [app.data{app.primaryJoin},app.data{app.primaryJoin}(:,end:-1:1)];
                app.lengthDataSecondary{app.primaryJoin} = 2*app.lengthData{app.secondaryJoin};
                app.lengthDataSecondary{app.secondaryJoin} = 2*app.lengthData{app.primaryJoin};
            else
                app.dataSecondary{app.primaryJoin} = app.data{app.secondaryJoin};
                app.dataSecondary{app.secondaryJoin} = app.data{app.primaryJoin};
                app.lengthDataSecondary{app.primaryJoin} = app.lengthData{app.secondaryJoin};
                app.lengthDataSecondary{app.secondaryJoin} = app.lengthData{app.primaryJoin};
            end
            
            app.plotAllStaticTimeSeries();
            app.updateAllDynamicTimeSeries();
            app.plotPMP();
        end
        
        function updateYAxisLimits(app, minYValue, maxYValue)
            app.minYValue = minYValue;
            app.maxYValue = maxYValue;
            
            ylim(app.UIAxesPMP,[app.minYValue, app.maxYValue]);
            
            %reposition the cursor within bounds
            scp = app.surfaceCursorPosition{1}(2);
            app.surfaceCursorPosition{1}(2) = max(min(scp,maxYValue), minYValue);
            scp = app.surfaceCursorPosition{2}(2);
            app.surfaceCursorPosition{2}(2) = max(min(scp,maxYValue), minYValue);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataA, PMPA, profileIndicesA, dataB, PMPB, profileIndicesB, subLengthSeries, yAxis, yLabel, title)
            %%%%%%%%%%%%%%%%%%%%%%%%
            %%% Input Validation %%%
            %%%%%%%%%%%%%%%%%%%%%%%%
%             errorEncountered = true;
%             if length(dataA) ~= size(PMPA,2)
%                 fprintf('ERROR: Mismatched Dimensions: dataA: %d, size(PMPA,2): %d\n',length(dataA), size(PMPA,2));
%             elseif length(dataB) ~= size(PMPB,2)
%                 fprintf('ERROR: Mismatched Dimensions: dataB: %d, size(PMPB,2): %d\n',length(dataB), size(PMPB,2));
%             elseif max(profileIndicesA,[],"all") > length(dataB)
%                 fprintf('ERROR: Max profileIndexA must be less than length of dataB: largest index: %d, length(dataB)\n',max(profileIndicesA,[],"all"), length(dataB));
%             elseif max(profileIndicesB,[],"all") > length(dataA)
%                 fprintf('ERROR: Max profileIndexB must be less than length of dataA: largest index: %d, length(dataA)\n',max(profileIndicesB,[],"all"), length(dataA));
%             elseif size(PMPA,1) ~= length(subLengthSeries)
%                 fprintf('ERROR: Number of rows in Pan Matrix Profile A (%d) must equal number of values in subLengthSeries (%d)\n',size(PMPA,1),length(subLengthSeries));
%             elseif size(PMPB,1) ~= length(subLengthSeries)
%                 fprintf('ERROR: Number of rows in Pan Matrix Profile B (%d) must equal number of values in subLengthSeries (%d)\n',size(PMPB,1),length(subLengthSeries));
%             elseif length(subLengthSeries) ~= 1 && size(yAxis) ~= size(subLengthSeries)
%                 fprintf('Error: yAxis and subLengthSeries dimensions must match if subLengthSeries not a single value.');
%             else
%                 errorEncountered = false;
%             end
%             if errorEncountered
%                 closereq;
%                 return;
%             end


            %%%%%%%%%%%%%%%%%
            %%% Join Mode %%%
            %%%%%%%%%%%%%%%%%
            if isempty(dataB) %quick implementation to get it working
                app.joinMode = 'AA';
                dataB = dataA;
                PMPB = PMPA;
                profileIndicesB = profileIndicesA;
                app.JoinModeSwitch.Enable = false;
                app.JoinModeSwitch.Visible = false;
            else
                app.joinMode = 'AB';
            end
            
            app.primaryJoin = 1; %will be 2 swapped when JoinModeSwitch.Value == 'BA'
            app.secondaryJoin = 2; %will be 1 swapped when JoinModeSwitch.Value == 'BA'
            app.JoinModeSwitch.Value = 'AB';
            
            
            %%%%%%%%%%%%%%%%%%%%%%%
            %%% Input Variables %%%
            %%%%%%%%%%%%%%%%%%%%%%%
            app.title = title;
            app.UIFigure.Name = title;
            ylabel(app.UIAxesPMP, yLabel);
            
            if size(dataA,1) > size(dataA,2)
                app.data{1} = dataA';
            else
                app.data{1} = dataA;
            end
            
            if size(dataB,1) > size(dataB,2)
                app.data{2} = dataB';
            else
                app.data{2} = dataB;
            end
            
            %%% length of the second time series
            if isempty(profileIndicesB)
                app.dataSecondary{1} = app.data{2};
                app.dataSecondary{2} = app.data{1};
                app.lengthDataSecondary{1} = length(dataB);
                app.lengthDataSecondary{2} = length(dataA);
                app.UsesPalindromeCheckBox.Value = false;
            elseif max(profileIndicesB,[],"all") > length(dataA) || max(profileIndicesA,[],"all") > length(dataB)
                app.dataSecondary{1} = [app.data{2},app.data{2}(:,end:-1:1)];
                app.dataSecondary{2} = [app.data{1},app.data{1}(:,end:-1:1)];
                app.lengthDataSecondary{1} = 2*length(dataB);
                app.lengthDataSecondary{2} = 2*length(dataA);
                app.UsesPalindromeCheckBox.Value = true;
            else
                app.dataSecondary{1} = app.data{2};
                app.dataSecondary{2} = app.data{1};
                app.lengthDataSecondary{1} = length(dataB);
                app.lengthDataSecondary{2} = length(dataA);
                app.UsesPalindromeCheckBox.Value = false;
            end

            app.PMP{1} = PMPA;
            app.profileIndices{1} = profileIndicesA;
            
            app.PMP{2} = PMPB;
            app.profileIndices{2} = profileIndicesB;
            
            app.yAxis = yAxis;
            if length(subLengthSeries) == 1
                app.subLengthSeries = ones(size(app.yAxis))*subLengthSeries;
            else
                app.subLengthSeries = subLengthSeries;
            end
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Input Variable Basic Properties %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.lengthData{1} = size(app.data{1},2);
            app.minData{1} = min(app.data{1},[],2);
            app.maxData{1} = max(app.data{1},[],2);
            
            app.lengthData{2} = size(app.data{2},2);
            app.minData{2} = min(app.data{2},[],2);
            app.maxData{2} = max(app.data{2},[],2);
            
            app.maxPMP{1} = max(app.PMP{1}(~isnan(app.PMP{1})),[],'all');
            app.minPMP{1} = min(app.PMP{1}(~isnan(app.PMP{1})),[],'all');
            
            app.maxPMP{2} = max(app.PMP{2}(~isnan(app.PMP{2})),[],'all');
            app.minPMP{2} = min(app.PMP{2}(~isnan(app.PMP{2})),[],'all');
            

            app.numYValues = length(app.yAxis);
            app.minYValue = min(app.yAxis);
            app.maxYValue = max(app.yAxis);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Input Variables Computed Properties %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%% Use percentile values for evenly distributing brightness
            %%% values
            numPercentileValues = 11;
            ps = linspace(0,100,numPercentileValues)';
            app.percentileValues{1} = zeros(1,numPercentileValues);
            app.percentileValues{2} = zeros(1,numPercentileValues);
            
            for index=2:size(ps,1)
                app.percentileValues{1}(index) = prctile(app.PMP{1},ps(index),'all');
                app.percentileValues{2}(index) = prctile(app.PMP{2},ps(index),'all');
            end
            
            %%% Two colormaps used for representing MP distances and
            %%% profile indices of nearest neighbor
            app.colormapDistances = gray();
            app.colormapProfileIndices = colorcet('R1');
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Visualization Plots for Optimized Live Update %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.Primary_subsequenceOutlinePlot = 0;
            app.Primary_subsequenceColorPlot = 0;
            app.Primary_subsequenceMarkerOutlinePlot = 0;
            app.Primary_subsequenceMarkerColorPlot = 0;
            app.Secondary_subsequenceOutlinePlot = 0;
            app.Secondary_subsequenceColorPlot = 0;
            app.Secondary_subsequenceMarkerOutlinePlot = 0;
            app.Secondary_subsequenceMarkerColorPlot = 0;
            
            app.Primary_subsequenceZoomOutlinePlot = 0;
            app.Primary_subsequenceZoomColorPlot = 0;
            app.Primary_subsequenceZoomMarkerOutlinePlot = 0;
            app.Primary_subsequenceZoomMarkerColorPlot = 0;
%             app.subsequenceZoomOutlineNNPlot = 0;
%             app.subsequenceZoomColorNNPlot = 0;
%             app.subsequenceZoomMarkerOutlineNNPlot = 0;
%             app.subsequenceZoomMarkerColorNNPlot = 0;


            app.zoomExclusionPlotLeft = 0;
            app.zoomExclusionPlotRight = 0;

            
            app.surfaceCursorPrimary1 = 0;
            app.surfaceCursorPrimary2 = 0;
            app.surfaceCursorPrimary3 = 0;
            app.surfaceCursorSecondary1 = 0;
            app.surfaceCursorSecondary2 = 0;
            app.surfaceCursorSecondary3 = 0;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Visualization Global State %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.startIndex{1} = 1;
            app.endIndex{1} = app.lengthData{1};
            app.centerIndex{1} = ceil(app.lengthData{1}/2);
            
            app.startIndex{2} = 1;
            app.endIndex{2} = app.lengthData{2};
            app.centerIndex{2} = ceil(app.lengthData{2}/2);
            
            
            medianYValue = ceil((app.maxYValue + app.minYValue)/2);
            [ ~, app.yAxisIndex ] = min( abs( app.subLengthSeries - medianYValue ) );
            app.motifTimeIndex1 = ceil(app.lengthData{1}/2);
            app.motifTimeIndex2 = app.profileIndices{1}(app.yAxisIndex,app.motifTimeIndex1);
            
            if size(app.data{app.primaryJoin},1) == 1
               yAxisIndex = 1;
            else
               yAxisIndex = app.yAxisIndex;
            end
            app.surfaceCursorPosition{1} = [app.lengthData{1}/2, app.yAxis(end)/2, app.maxData{1}(yAxisIndex) + 1];
            app.surfaceCursorPosition{2} = [app.lengthData{2}/2, app.yAxis(end)/2, app.maxData{2}(yAxisIndex) + 1];
            
            
            app.colormapStartIndex{1} = 1;
            app.colormapEndIndex{1} = app.lengthData{1};
            
            app.colormapStartIndex{2} = 1;
            app.colormapEndIndex{2} = app.lengthData{2};
            
            app.ColormapStartIndexEditField.Value = app.colormapStartIndex{1};
            app.ColormapEndIndexEditField.Value = app.colormapEndIndex{1};
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Visualization Misc Variables %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cm = zeros(2,size(app.colormapProfileIndices,1),3);
            cm(1,:,:) = app.colormapProfileIndices;
            cm(2,:,:) = app.colormapProfileIndices;
            app.timeSeriesColormap = cm;
            
            
            %%%%%%%%%%%%%%%%
            %%% UI Modes %%%
            %%%%%%%%%%%%%%%%
            %%% PMP Sliders
            app.XSurfaceSlider.Value = 0.5;
            app.YSurfaceSlider.Value = 0.5;
            
            %%% Tab TimeSeries
            app.ZoomIndexSlider.Value = 0.5;
            
            upperPowerLimit = log10(app.lengthData{1});
            app.ZoomSlider.Limits = [2,upperPowerLimit];
            app.ZoomEditField.Value = app.lengthData{1};
            
            
            %%% Tab Options
            app.CONTRAST_LOW = 1;
            app.CONTRAST_HIGH = 2;
            app.CONTRAST_BINARY = 3;
            app.CONTRAST_EXACT = 4;
            app.contrastMode = app.CONTRAST_HIGH;
            app.HighContrastButton.Value = true;
            
            thresholdValue = app.getThresholdFromPercentile(50);
            
            app.ThresholdSlider.Limits = [-1,1];%[app.minPMP{1}, app.maxPMP{1}];
            app.ThresholdSlider.Value = thresholdValue;
            app.ThresholdEditField.Limits = [-1,1];%[app.minPMP{1}, app.maxPMP{1}];
            app.ThresholdEditField.Value = thresholdValue;
            
            app.PercentileSlider.Limits = [-100,100];
            app.PercentileSlider.Value = 50;
            app.PercentileEditField.Limits = [-100,100];
            app.PercentileEditField.Value = 50;


            app.showProfileIndicies = true;
            app.ProfileIndexOverlayCheckBox.Value = app.showProfileIndicies;
            
            app.liveUpdateMotifs = true;
            app.LiveUpdateMotifsCheckBox.Value = app.liveUpdateMotifs;
            
            app.MinParamValueEditField.Value = app.yAxis(1);
            app.MaxParamValueEditField.Value = app.yAxis(end);
            
            app.consecutiveCenteringClick = 0;
            
            app.logarithmicYAxis = false;
            app.LogarithmicYaxisCheckBox.Value = false;
            
            app.ScaleSweepCheckBox.Value = false;
            app.useScaleSweep = false;
            
            %%%%%%%%%%%%%%%%%%%%%%%%
            %%% Initialize Plots %%%
            %%%%%%%%%%%%%%%%%%%%%%%%
            
            app.plotMotifs();
            app.plotStaticTimeSeriesPrimary();
            app.plotStaticTimeSeriesSecondary();
            app.plotStaticTimeSeriesPrimaryZoomed();
            %%% Horizontal colorbar representing the time series index
            %%% Uses two rows of an RGB colormap so that will be a
            %%%     stretched 2D image


            app.plotSurface();
%             colormap(app.UIAxesPMP,app.colormapDistances);
%             set(app.UIAxesPMP, 'YScale', 'log');
            ylim(app.UIAxesPMP,[app.minYValue, app.maxYValue]);
            app.plotColorbar();
            
            
            app.displayCursorInfo();

            disableDefaultInteractivity(app.UIAxesMotifs);
            disableDefaultInteractivity(app.UIAxesTimeSeriesPrimary);
            disableDefaultInteractivity(app.UIAxesTimeSeriesPrimaryZoom);
            disableDefaultInteractivity(app.UIAxesPMP);
            
            app.counter = 0;
            app.lastRefreshTime = seconds(days(now));
            app.timeSeriesFPS = 3;
        end

        % Value changed function: ZoomIndexSlider
        function ZoomIndexSliderValueChanged(app, event)
              app.plotPMP();
              app.centerIndex{app.primaryJoin} = ceil(app.ZoomIndexSlider.Value*app.lengthData{app.primaryJoin});
              
              app.consecutiveCenteringClick = 0;
              
              app.updateDynamicTimeSeriesPrimary();
              app.updateDynamicTimeSeriesSecondary();
              app.updateDynamicTimeSeriesPrimaryZoomed();
        end

        % Value changed function: ZoomSlider
        function ZoomSliderValueChanged(app, event)
            zoomPower = app.ZoomSlider.Value;
           
            app.ZoomEditField.Value = power(10,zoomPower);
            app.plotPMP();
            app.updateDynamicTimeSeriesPrimary();
            app.updateDynamicTimeSeriesSecondary();
            app.updateDynamicTimeSeriesPrimaryZoomed();
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            thresholdValue = app.ThresholdSlider.Value;
            
            thresholdValue = min(thresholdValue, app.maxPMP{app.primaryJoin});
            percentileValue = app.getPercentileFromThreshold(thresholdValue);
            
            app.ThresholdEditField.Value = thresholdValue;
            app.PercentileEditField.Value = percentileValue;
            app.PercentileSlider.Value = percentileValue;
            
            app.plotSurface();
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            thresholdValue = app.ThresholdEditField.Value;
            
            thresholdValue = min(thresholdValue, app.maxPMP{app.primaryJoin});
            percentileValue = app.getPercentileFromThreshold(thresholdValue);
            
            app.ThresholdSlider.Value = thresholdValue;
            app.PercentileEditField.Value = percentileValue;
            app.PercentileSlider.Value = percentileValue;
            
            app.plotSurface();       
        end

        % Callback function
        function SelectPointButtonPushed(app, event)
            
        end

        % Callback function
        function ZoomEditFieldValueChanged(app, event)
            zoomWindowLength = app.ZoomEditField.Value;
            
            %update slider UI element position
            app.ZoomSlider.Value = log10(zoomWindowLength);
            
            %update zoom window
            app.updateAllDynamicTimeSeries();
            app.plotPMP();
            
        end

        % Value changing function: ZoomSlider
        function ZoomSliderValueChanging(app, event)
            zoomPower = event.Value;
            
            
            %update editField with number
            zoomWindowLength = ceil(power(10,zoomPower));
            app.ZoomEditField.Value = zoomWindowLength;
            
            %update zoom visualization

            app.updateZoomExclusionZone(app.centerIndex{app.primaryJoin}, zoomWindowLength);

            startIndex  = max(ceil(app.centerIndex{app.primaryJoin} - zoomWindowLength/2),1);
            endIndex    = min(ceil(app.centerIndex{app.primaryJoin} + zoomWindowLength/2), app.lengthData{app.primaryJoin});

            if true || (startIndex >= app.startIndex{app.primaryJoin} && endIndex <= app.endIndex{app.primaryJoin})
                xlim(app.UIAxesPMP,[startIndex,endIndex]);
            end
    
            
        end

        % Value changing function: ZoomIndexSlider
        function ZoomIndexSliderValueChanging(app, event)
            app.centerIndex{app.primaryJoin} = event.Value * app.lengthData{app.primaryJoin};
            subLen = app.endIndex{app.primaryJoin} - app.startIndex{app.primaryJoin};
            app.displayCursorInfo();
            app.updateZoomExclusionZone(app.centerIndex{app.primaryJoin}, subLen);
            
            app.consecutiveCenteringClick = 0;
        end

        % Value changing function: XSurfaceSlider
        function XSurfaceSliderValueChanging(app, event)
            timeSeriesIndexRatio = event.Value;
            surfaceXRange = app.endIndex{app.primaryJoin} - app.startIndex{app.primaryJoin};
            cursorOffset = timeSeriesIndexRatio * surfaceXRange;
            timeSeriesIndex = app.startIndex{app.primaryJoin} + cursorOffset;
            app.motifTimeIndex1 = ceil(timeSeriesIndex);
            app.motifTimeIndex2 = app.profileIndices{app.primaryJoin}(app.yAxisIndex,app.motifTimeIndex1);
            
            app.displayCursorInfo();
            
            app.surfaceCursorPosition{app.primaryJoin}(1) = timeSeriesIndex;
            app.plotSurfaceCursor();
            
            app.timeSeriesFPS = 3;
            if app.liveUpdateMotifs == true && app.requestTimeSeriesRefresh() == true
                app.updateAllDynamicTimeSeries();
            end
        end

        % Value changed function: XSurfaceSlider
        function XSurfaceSliderValueChanged(app, event)
            timeSeriesIndexRatio = app.XSurfaceSlider.Value;
            surfaceXRange = app.endIndex{app.primaryJoin} - app.startIndex{app.primaryJoin};
            cursorOffset = timeSeriesIndexRatio * surfaceXRange;
            timeSeriesIndex = app.startIndex{app.primaryJoin} + cursorOffset;
            app.motifTimeIndex1 = ceil(timeSeriesIndex);
            app.motifTimeIndex2 = app.profileIndices{app.primaryJoin}(app.yAxisIndex,app.motifTimeIndex1);
            
            app.displayCursorInfo();


            app.surfaceCursorPosition{app.primaryJoin}(1) = timeSeriesIndex;
            app.plotSurfaceCursor();


            app.updateAllDynamicTimeSeries();
            
%             app.plotColorbar();


        end

        % Value changing function: YSurfaceSlider
        function YSurfaceSliderValueChanging(app, event)
            if app.logarithmicYAxis == true
                sliderRatio = event.Value;
                rangeYValue = log10(app.maxYValue)-log10(app.minYValue);
                idealYValue = rangeYValue*sliderRatio + log10(app.minYValue);
                idealYValue = power(10,idealYValue);
            else
                sliderRatio = event.Value;
                rangeYValue = app.maxYValue-app.minYValue;
                idealYValue = rangeYValue*sliderRatio + app.minYValue;
            end
        

            app.surfaceCursorPosition{app.primaryJoin}(2) = idealYValue;
            
            %%% calculate the index for the motif plot
            app.yAxisIndex = app.getIndexFromIdealYValue(idealYValue);
            app.motifTimeIndex2 = app.profileIndices{app.primaryJoin}(app.yAxisIndex,app.motifTimeIndex1);
            
            app.plotSurfaceCursor();

            if app.liveUpdateMotifs == true && app.requestTimeSeriesRefresh() == true
%                 app.plotAllStaticTimeSeries();
                app.updateAllDynamicTimeSeries();
            end
            
            app.displayCursorInfo();
        end

        % Value changed function: YSurfaceSlider
        function YSurfaceSliderValueChanged(app, event)
            if app.logarithmicYAxis == true
                sliderRatio = event.Value;
                rangeYValue = log10(app.maxYValue)-log10(app.minYValue);
                idealYValue = rangeYValue*sliderRatio + log10(app.minYValue);
                idealYValue = power(10,idealYValue);
            else
                sliderRatio = event.Value;
                rangeYValue = app.maxYValue-app.minYValue;
                idealYValue = rangeYValue*sliderRatio + app.minYValue;
            end
            
            app.surfaceCursorPosition{app.primaryJoin}(2) = idealYValue;
            
            %%% calculate the index for the motif plot
            app.yAxisIndex = app.getIndexFromIdealYValue(idealYValue);
            app.motifTimeIndex2 = app.profileIndices{app.primaryJoin}(app.yAxisIndex,app.motifTimeIndex1);
            
            app.plotSurfaceCursor();

            app.plotAllStaticTimeSeries();
            app.updateAllDynamicTimeSeries();
           
            app.displayCursorInfo();

        end

        % Callback function
        function SavePlotMenuSelected(app, event)
            
        end

        % Callback function
        function ButtonThresholdPlusPushed(app, event)
            
        end

        % Callback function
        function ButtonThresholdMinusPushed(app, event)
            
        end

        % Selection changed function: ContrastButtonGroup
        function ContrastButtonGroupSelectionChanged(app, event)
            buttonVector = ismember(app.ContrastButtonGroup.Buttons,app.ContrastButtonGroup.SelectedObject);
            buttonNumber = find(buttonVector,1,"last");
            app.contrastMode = buttonNumber;
            
            app.plotSurface();
        end

        % Value changed function: ProfileIndexOverlayCheckBox
        function ProfileIndexOverlayCheckBoxValueChanged(app, event)
            value = app.ProfileIndexOverlayCheckBox.Value;
            app.showProfileIndicies = value;
            app.plotColorbar();
            app.plotSurface();
        end

        % Value changed function: LiveUpdateMotifsCheckBox
        function LiveUpdateMotifsCheckBoxValueChanged(app, event)
            value = app.LiveUpdateMotifsCheckBox.Value;
            app.liveUpdateMotifs = value;
        end

        % Value changed function: JoinModeSwitch
        function JoinModeSwitchValueChanged(app, event)
            value = app.JoinModeSwitch.Value;
            if value == "AB"
                app.primaryJoin = 1;
                app.secondaryJoin = 2;
            elseif value == "BA"
                app.primaryJoin = 2;
                app.secondaryJoin = 1;
            end
            app.switchJoinMode();
        end

        % Value changed function: PercentileSlider
        function PercentileSliderValueChanged(app, event)
            percentileValue = app.PercentileSlider.Value;
            
            if percentileValue > 0
                thresholdValue = app.getThresholdFromPercentile(percentileValue);
            else
               thresholdValue = -1*app.getThresholdFromPercentile(-1*percentileValue);
            end
            app.ThresholdEditField.Value = thresholdValue;
            app.ThresholdSlider.Value = thresholdValue;
            app.PercentileEditField.Value = percentileValue;
            
            app.plotSurface();
        end

        % Value changing function: PercentileSlider
        function PercentileSliderValueChanging(app, event)
            percentileValue = event.Value;
            
            if percentileValue > 0
                thresholdValue = app.getThresholdFromPercentile(percentileValue);
            else
               thresholdValue = -1*app.getThresholdFromPercentile(-1*percentileValue);
            end
            app.ThresholdEditField.Value = thresholdValue;
            app.ThresholdSlider.Value = thresholdValue;
            app.PercentileEditField.Value = percentileValue;
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            thresholdValue = event.Value;
            
            thresholdValue = min(thresholdValue, app.maxPMP{app.primaryJoin});
            percentileValue = app.getPercentileFromThreshold(thresholdValue);
            
            app.ThresholdEditField.Value = thresholdValue;
%             app.ThresholdSlider.Value = thresholdValue;
            app.PercentileEditField.Value = percentileValue;
            app.PercentileSlider.Value = percentileValue;
        end

        % Value changed function: UsesPalindromeCheckBox
        function UsesPalindromeCheckBoxValueChanged(app, event)
            value = app.UsesPalindromeCheckBox.Value;
            app.updatePalindromeSettings(value);
        end

        % Callback function
        function MaxSubLengthSliderValueChanged(app, event)
           
        end

        % Callback function
        function MaxSubLengthSliderValueChanging(app, event)
     
        end

        % Callback function
        function MinSubLengthSliderValueChanged(app, event)
            
        end

        % Callback function
        function MinSubLengthSliderValueChanging(app, event)
   
        end

        % Callback function
        function MaxSubLengthEditFieldValueChanged(app, event)
            
        end

        % Value changed function: MinParamValueEditField
        function MinParamValueEditFieldValueChanged(app, event)
            yValue = app.MinParamValueEditField.Value;
            [~,yAxisIndex] = min(abs(app.yAxis - yValue));
            %prevent min from being greater than max
            [~,maxYAxisIndex] = min(abs(app.yAxis - app.maxYValue));
            yAxisIndex = min(maxYAxisIndex-1, yAxisIndex);
            
            yValue = app.subLengthSeries(yAxisIndex);
            app.MinParamValueEditField.Value = yValue;

            app.updateYAxisLimits(yValue, app.maxYValue);
        end

        % Value changed function: MaxParamValueEditField
        function MaxParamValueEditFieldValueChanged2(app, event)
            yValue = app.MaxParamValueEditField.Value;
            [~,yAxisIndex] = min(abs(app.yAxis - yValue));

            [~,minYAxisIndex] = min(abs(app.yAxis - app.minYValue));
            yAxisIndex = max(minYAxisIndex+1, yAxisIndex);
            
            yValue = app.yAxis(yAxisIndex);
            app.updateYAxisLimits(app.minYValue, yValue);
            app.MaxParamValueEditField.Value = yValue;

        end

        % Callback function
        function loclclrCheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function LocalColorButtonPushed(app, event)
            
        end

        % Value changed function: LocalColorButton
        function LocalColorButtonValueChanged(app, event)
            value = app.LocalColorButton.Value;
            if value == true
                app.colormapStartIndex{app.primaryJoin} = app.ColormapStartIndexEditField.Value;
                app.colormapEndIndex{app.primaryJoin} = app.ColormapEndIndexEditField.Value;
            else
                app.colormapStartIndex{app.primaryJoin} = 1;
                app.colormapEndIndex{app.primaryJoin} = app.lengthDataSecondary{app.primaryJoin};
            end
            app.plotAllStaticTimeSeries();
            app.updateAllDynamicTimeSeries();
            app.plotSurface()
        end

        % Value changed function: ColormapStartIndexEditField
        function ColormapStartIndexEditFieldValueChanged(app, event)
            value = app.ColormapStartIndexEditField.Value;
            app.colormapStartIndex{app.primaryJoin} = value;
            
            app.plotAllStaticTimeSeries();
            app.updateAllDynamicTimeSeries();
            app.plotSurface()
        end

        % Value changed function: ColormapEndIndexEditField
        function ColormapEndIndexEditFieldValueChanged(app, event)
            value = app.ColormapEndIndexEditField.Value;
            app.colormapEndIndex{app.primaryJoin} = value;
            
            app.plotAllStaticTimeSeries();
            app.updateAllDynamicTimeSeries();
            app.plotSurface()
        end

        % Button pushed function: JumpLeftButton
        function JumpLeftButtonPushed(app, event)
            range = app.endIndex{app.primaryJoin} - app.startIndex{app.primaryJoin};
            app.startIndex{app.primaryJoin} = ceil(app.startIndex{app.primaryJoin} - 0.8*range);
            app.startIndex{app.primaryJoin} = max(app.startIndex{app.primaryJoin}, 1);
            app.endIndex{app.primaryJoin} = app.startIndex{app.primaryJoin} + range;
            app.centerIndex{app.primaryJoin} = ceil(app.startIndex{app.primaryJoin} + range/2);
            
            app.consecutiveCenteringClick = 0;
            
            app.updateAllDynamicTimeSeries();
            app.plotSurface();
            
            app.ZoomIndexSlider.Value = app.centerIndex{app.primaryJoin}/app.lengthData{app.primaryJoin};
        end

        % Button pushed function: JumpRightButton
        function JumpRightButtonPushed(app, event)
            range = app.endIndex{app.primaryJoin} - app.startIndex{app.primaryJoin};
            app.startIndex{app.primaryJoin} = ceil(app.startIndex{app.primaryJoin} + 0.8*range);
            app.startIndex{app.primaryJoin} = min(app.startIndex{app.primaryJoin}, app.lengthData{app.primaryJoin}-range+1);
            app.endIndex{app.primaryJoin} = app.startIndex{app.primaryJoin} + range-1;
            app.centerIndex{app.primaryJoin} = ceil(app.startIndex{app.primaryJoin} + range/2);
            
            app.consecutiveCenteringClick = 0;
            
            app.updateAllDynamicTimeSeries();
            app.plotSurface();
            
            app.ZoomIndexSlider.Value = app.centerIndex{app.primaryJoin}/app.lengthData{app.primaryJoin};
        end

        % Button pushed function: CenterCursorButton
        function CenterCursorButtonPushed(app, event)
            if app.consecutiveCenteringClick == 0
                desiredPosition = app.surfaceCursorPosition{app.primaryJoin}(1);
            else
                desiredPosition = app.surfaceCursorPosition{app.primaryJoin}(1) + ceil(app.surfaceCursorPosition{app.primaryJoin}(2)/2);
            end
            range = app.endIndex{app.primaryJoin} - app.startIndex{app.primaryJoin};
            app.startIndex{app.primaryJoin} = ceil(desiredPosition - 0.5*range);
            app.startIndex{app.primaryJoin} = max(app.startIndex{app.primaryJoin}, 1);
            app.endIndex{app.primaryJoin} = app.startIndex{app.primaryJoin} + range;
            app.centerIndex{app.primaryJoin} = ceil(app.startIndex{app.primaryJoin} + range/2);
                
            app.consecutiveCenteringClick = 1 - app.consecutiveCenteringClick;
            
            app.updateAllDynamicTimeSeries();
            app.plotSurface();
            
            app.ZoomIndexSlider.Value = app.centerIndex{app.primaryJoin}/app.lengthData{app.primaryJoin};
        end

        % Value changed function: ZoomEditField
        function ZoomEditFieldValueChanged2(app, event)
            value = app.ZoomEditField.Value;
            
            %update slider UI element position
            app.ZoomSlider.Value = log10(value);
            app.startIndex{app.primaryJoin} = app.centerIndex{app.primaryJoin} - ceil(value/2);
            app.endIndex{app.primaryJoin} = app.startIndex{app.primaryJoin} + value -1;
            
            
            %update zoom window
            app.updateAllDynamicTimeSeries();
%             app.updateZoomExclusionZone(app.centerIndex{app.primaryJoin}, value);
            app.plotPMP();
        end

        % Value changed function: LogarithmicYaxisCheckBox
        function LogarithmicYaxisCheckBoxValueChanged(app, event)
            value = app.LogarithmicYaxisCheckBox.Value;
            app.logarithmicYAxis = value;
            
            app.plotSurface();
        end

        % Value changed function: ScaleSweepCheckBox
        function ScaleSweepCheckBoxValueChanged(app, event)
            value = app.ScaleSweepCheckBox.Value;
            app.useScaleSweep = value;
        end

        % Value changing function: MotifSquishSlider
        function MotifSquishSliderValueChanging(app, event)
            app.plotMotifs();
        end

        % Value changed function: MotifSquishSlider
        function MotifSquishSliderValueChanged(app, event)
            app.plotMotifs();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesPMP = uiaxes(app.UIFigure);
            xlabel(app.UIAxesPMP, 'Timeseries Index')
            ylabel(app.UIAxesPMP, 'Subsequence Length')
            app.XSurfaceSlider = uislider(app.UIFigure);
            app.XSurfaceSlider.Limits = [0 1];
            app.XSurfaceSlider.MajorTicks = [];
            app.XSurfaceSlider.ValueChangedFcn = createCallbackFcn(app, @XSurfaceSliderValueChanged, true);
            app.XSurfaceSlider.ValueChangingFcn = createCallbackFcn(app, @XSurfaceSliderValueChanging, true);
            app.XSurfaceSlider.MinorTicks = [];
            app.XSurfaceSlider.Value = 0.5;
            app.YSurfaceSlider = uislider(app.UIFigure);
            app.YSurfaceSlider.Limits = [0 1];
            app.YSurfaceSlider.MajorTicks = [];
            app.YSurfaceSlider.ValueChangedFcn = createCallbackFcn(app, @YSurfaceSliderValueChanged, true);
            app.YSurfaceSlider.ValueChangingFcn = createCallbackFcn(app, @YSurfaceSliderValueChanging, true);
            app.YSurfaceSlider.MinorTicks = [];
            app.YSurfaceSlider.Value = 0.5;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeseriesPlotsTab = uitab(app.TabGroup);
            app.TimeseriesPlotsTab.Title = 'Timeseries Plots';
            app.UIAxesTimeSeriesPrimaryZoom = uiaxes(app.TimeseriesPlotsTab);
            app.UIAxesTimeSeriesPrimaryZoom.XTick = [];
            app.UIAxesTimeSeriesPrimaryZoom.YTick = [];
            app.UIAxesMotifs = uiaxes(app.TimeseriesPlotsTab);
            app.UIAxesMotifs.XTick = [];
            app.UIAxesMotifs.YTick = [];
            app.ZoomSlider = uislider(app.TimeseriesPlotsTab);
            app.ZoomSlider.Limits = [2 7];
            app.ZoomSlider.MajorTicks = [];
            app.ZoomSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomSliderValueChanged, true);
            app.ZoomSlider.ValueChangingFcn = createCallbackFcn(app, @ZoomSliderValueChanging, true);
            app.ZoomSlider.MinorTicks = [];
            app.ZoomSlider.Value = 5;
            app.UIAxesTimeSeriesPrimary = uiaxes(app.TimeseriesPlotsTab);
            app.UIAxesTimeSeriesPrimary.YTick = [];
            app.ZoomIndexSlider = uislider(app.TimeseriesPlotsTab);
            app.ZoomIndexSlider.Limits = [0 1];
            app.ZoomIndexSlider.MajorTicks = [];
            app.ZoomIndexSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomIndexSliderValueChanged, true);
            app.ZoomIndexSlider.ValueChangingFcn = createCallbackFcn(app, @ZoomIndexSliderValueChanging, true);
            app.ZoomIndexSlider.MinorTicks = [];
            app.ZoomIndexSlider.Value = 0.5;
            app.ZoomEditFieldLabel = uilabel(app.TimeseriesPlotsTab);
            app.ZoomEditFieldLabel.Text = 'Zoom';
            app.ZoomEditField = uieditfield(app.TimeseriesPlotsTab, 'numeric');
            app.ZoomEditField.ValueChangedFcn = createCallbackFcn(app, @ZoomEditFieldValueChanged2, true);
            app.LabelSubLen = uilabel(app.TimeseriesPlotsTab);
            app.LabelTimeIndex = uilabel(app.TimeseriesPlotsTab);
            app.LabelRawPMP = uilabel(app.TimeseriesPlotsTab);
            app.LabelTimeIndexNN = uilabel(app.TimeseriesPlotsTab);
            app.UIAxesTimeSeriesSecondary = uiaxes(app.TimeseriesPlotsTab);
            app.UIAxesTimeSeriesSecondary.YTick = [];
            app.PrimaryLabel = uilabel(app.TimeseriesPlotsTab);
            app.PrimaryLabel.Text = 'A';
            app.SecondaryLabel = uilabel(app.TimeseriesPlotsTab);
            app.SecondaryLabel.Text = 'B';
            app.PrimaryZoomLabel = uilabel(app.TimeseriesPlotsTab);
            app.PrimaryZoomLabel.Text = 'A Zoom';
            app.JoinModeSwitch = uiswitch(app.TimeseriesPlotsTab, 'rocker');
            app.JoinModeSwitch.Items = {'AB', 'BA'};
            app.JoinModeSwitch.ValueChangedFcn = createCallbackFcn(app, @JoinModeSwitchValueChanged, true);
            app.JoinModeSwitch.Value = 'AB';
            app.LocalColorButton = uibutton(app.TimeseriesPlotsTab, 'state');
            app.LocalColorButton.ValueChangedFcn = createCallbackFcn(app, @LocalColorButtonValueChanged, true);
            app.LocalColorButton.Text = 'Local Color';
            app.ColormapStartIndexEditField = uieditfield(app.TimeseriesPlotsTab, 'numeric');
            app.ColormapStartIndexEditField.ValueChangedFcn = createCallbackFcn(app, @ColormapStartIndexEditFieldValueChanged, true);
            app.ColormapEndIndexEditField = uieditfield(app.TimeseriesPlotsTab, 'numeric');
            app.ColormapEndIndexEditField.ValueChangedFcn = createCallbackFcn(app, @ColormapEndIndexEditFieldValueChanged, true);
            app.JumpLeftButton = uibutton(app.TimeseriesPlotsTab, 'push');
            app.JumpLeftButton.ButtonPushedFcn = createCallbackFcn(app, @JumpLeftButtonPushed, true);
            app.JumpLeftButton.Text = '<';
            app.CenterCursorButton = uibutton(app.TimeseriesPlotsTab, 'push');
            app.CenterCursorButton.ButtonPushedFcn = createCallbackFcn(app, @CenterCursorButtonPushed, true);
            app.CenterCursorButton.Text = '-';
            app.JumpRightButton = uibutton(app.TimeseriesPlotsTab, 'push');
            app.JumpRightButton.ButtonPushedFcn = createCallbackFcn(app, @JumpRightButtonPushed, true);
            app.JumpRightButton.Text = '>';
            app.MotifSquishSlider = uislider(app.TimeseriesPlotsTab);
            app.MotifSquishSlider.Limits = [0 1];
            app.MotifSquishSlider.MajorTicks = [];
            app.MotifSquishSlider.ValueChangedFcn = createCallbackFcn(app, @MotifSquishSliderValueChanged, true);
            app.MotifSquishSlider.ValueChangingFcn = createCallbackFcn(app, @MotifSquishSliderValueChanging, true);
            app.MotifSquishSlider.MinorTicks = [];
            app.MotifSquishSlider.Value = 1;
            app.ContrastTab = uitab(app.TabGroup);
            app.ContrastTab.Title = 'Contrast';
            app.ContrastButtonGroup = uibuttongroup(app.ContrastTab);
            app.ContrastButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ContrastButtonGroupSelectionChanged, true);
            app.ContrastButtonGroup.Title = 'Contrast';
            app.LowContrastButton = uiradiobutton(app.ContrastButtonGroup);
            app.LowContrastButton.Text = 'Low Contrast';
            app.LowContrastButton.Value = true;
            app.HighContrastButton = uiradiobutton(app.ContrastButtonGroup);
            app.HighContrastButton.Text = 'High Contrast';
            app.BinaryButton = uiradiobutton(app.ContrastButtonGroup);
            app.BinaryButton.Text = 'Binary';
            app.PefectMatchButton = uiradiobutton(app.ContrastButtonGroup);
            app.PefectMatchButton.Text = 'Pefect Match';
            app.ProfileIndexOverlayCheckBox = uicheckbox(app.ContrastTab);
            app.ProfileIndexOverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProfileIndexOverlayCheckBoxValueChanged, true);
            app.ProfileIndexOverlayCheckBox.Text = 'Profile Index Overlay';
            app.ThresholdEditFieldLabel = uilabel(app.ContrastTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ContrastTab, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 0.33;
            app.ThresholdSlider = uislider(app.ContrastTab);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Value = 0.33;
            app.PercentileEditFieldLabel = uilabel(app.ContrastTab);
            app.PercentileEditFieldLabel.Text = 'Percentile';
            app.PercentileEditField = uieditfield(app.ContrastTab, 'numeric');
            app.PercentileEditField.Limits = [0 1];
            app.PercentileEditField.Value = 0.33;
            app.PercentileSlider = uislider(app.ContrastTab);
            app.PercentileSlider.MajorTicks = [];
            app.PercentileSlider.ValueChangedFcn = createCallbackFcn(app, @PercentileSliderValueChanged, true);
            app.PercentileSlider.ValueChangingFcn = createCallbackFcn(app, @PercentileSliderValueChanging, true);
            app.PercentileSlider.MinorTicks = [];
            app.PercentileSlider.Value = 0.33;
            app.ContrastCenteringLabel = uilabel(app.ContrastTab);
            app.ContrastCenteringLabel.Text = 'Contrast Centering';
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.UsesPalindromeCheckBox = uicheckbox(app.ConfigTab);
            app.UsesPalindromeCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsesPalindromeCheckBoxValueChanged, true);
            app.UsesPalindromeCheckBox.Text = 'Uses Palindrome';
            app.LiveUpdateMotifsCheckBox = uicheckbox(app.ConfigTab);
            app.LiveUpdateMotifsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveUpdateMotifsCheckBoxValueChanged, true);
            app.LiveUpdateMotifsCheckBox.Text = 'Live Update Motifs';
            app.MaxParamValueEditFieldLabel = uilabel(app.ConfigTab);
            app.MaxParamValueEditFieldLabel.Text = 'Max Param Value';
            app.MaxParamValueEditField = uieditfield(app.ConfigTab, 'numeric');
            app.MaxParamValueEditField.ValueChangedFcn = createCallbackFcn(app, @MaxParamValueEditFieldValueChanged2, true);
            app.MinParamValueEditFieldLabel = uilabel(app.ConfigTab);
            app.MinParamValueEditFieldLabel.Text = 'Min Param Value';
            app.MinParamValueEditField = uieditfield(app.ConfigTab, 'numeric');
            app.MinParamValueEditField.ValueChangedFcn = createCallbackFcn(app, @MinParamValueEditFieldValueChanged, true);
            app.LogarithmicYaxisCheckBox = uicheckbox(app.ConfigTab);
            app.LogarithmicYaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogarithmicYaxisCheckBoxValueChanged, true);
            app.LogarithmicYaxisCheckBox.Text = 'Logarithmic Y-axis';
            app.ScaleSweepCheckBox = uicheckbox(app.ConfigTab);
            app.ScaleSweepCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaleSweepCheckBoxValueChanged, true);
            app.ScaleSweepCheckBox.Text = 'Scale Sweep';
            app.DataBQueryfixedlengthCheckBox = uicheckbox(app.ConfigTab);
            app.DataBQueryfixedlengthCheckBox.Text = 'Data B Query (fixed length)';
            app.DataBQueryfixedlengthEditFieldLabel = uilabel(app.ConfigTab);
            app.DataBQueryfixedlengthEditFieldLabel.Text = 'Data B Query (fixed length)';
            app.DataBQueryfixedlengthEditField = uieditfield(app.ConfigTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualizeMMPAB_2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end